classdef control_new_offline_copy_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        custom_acquisition_button      matlab.ui.control.Button
        custom_acquisition_2_button    matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        z_coord_list                   matlab.ui.control.ListBox
        mm_button                      matlab.ui.control.Button
        save_dir_user_entry            matlab.ui.control.EditField
        browse_button                  matlab.ui.control.Button
        text2                          matlab.ui.control.Label
        lcp_rca_button                 matlab.ui.control.Button
        rcp_lca_button                 matlab.ui.control.Button
        text3                          matlab.ui.control.Label
        text4                          matlab.ui.control.Label
        sim_rotate_button              matlab.ui.control.Button
        text5                          matlab.ui.control.Label
        rLP_rca_button                 matlab.ui.control.Button
        rLP_lca_button                 matlab.ui.control.Button
        text6                          matlab.ui.control.Label
        text7                          matlab.ui.control.Label
        LP_angle_display               matlab.ui.control.Label
        LP_slider_display              matlab.ui.control.Label
        text13                         matlab.ui.control.Label
        camera_status                  matlab.ui.control.Label
        bf_button_rcp_rca              matlab.ui.control.Button
        text39                         matlab.ui.control.Label
        bf_button_lcp_lca              matlab.ui.control.Button
        coord_list                     matlab.ui.control.ListBox
        text42                         matlab.ui.control.Label
        go_to_coords_button            matlab.ui.control.Button
        add_new_coords_button          matlab.ui.control.Button
        text45                         matlab.ui.control.Label
        text46                         matlab.ui.control.Label
        save_coords_button             matlab.ui.control.Button
        load_coords_button             matlab.ui.control.Button
        calibrate_z_10x_button         matlab.ui.control.Button
        text50                         matlab.ui.control.Label
        calibrate_z_40x_button         matlab.ui.control.Button
        reset_camera_button            matlab.ui.control.Button
        text51                         matlab.ui.control.Label
        text52                         matlab.ui.control.Label
        text53                         matlab.ui.control.Label
        ill_pol_user_entry             matlab.ui.control.EditField
        det_pol_user_entry             matlab.ui.control.EditField
        text54                         matlab.ui.control.Label
        text55                         matlab.ui.control.Label
        ill_pol_rotate_button          matlab.ui.control.Button
        det_pol_rotate_button          matlab.ui.control.Button
        ill_pol_display                matlab.ui.control.Label
        det_pol_display                matlab.ui.control.Label
        clear_coords_button            matlab.ui.control.Button
        Panel2                         matlab.ui.container.Panel
        run_sp_af_stitch_button        matlab.ui.control.Button
        zstack_acquisition_button      matlab.ui.control.Button
        zstack_exp_time_user_entry     matlab.ui.control.EditField
        text49                         matlab.ui.control.Label
        run_mp_stitch_button           matlab.ui.control.Button
        text63                         matlab.ui.control.Label
        stitching_perimeter_display    matlab.ui.control.Label
        text65                         matlab.ui.control.Label
        stitching_acquisition_display  matlab.ui.control.Label
        run_sp_stitch_button           matlab.ui.control.Button
        run_sp_qBRM_stitch_button      matlab.ui.control.Button
        determine_z_focus_grid         matlab.ui.control.Button
        go_UL_button                   matlab.ui.control.Button
        go_LR_button                   matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        cornersTab                     matlab.ui.container.Tab
        calculate_grid_button          matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        set_UL_coords_button           matlab.ui.control.Button
        set_LR_coords_button           matlab.ui.control.Button
        cornergridTab                  matlab.ui.container.Tab
        set_grid_button                matlab.ui.control.Button
        Image_2                        matlab.ui.control.Image
        set_up_left_button             matlab.ui.control.Button
        set_low_right_button           matlab.ui.control.Button
        XYstageControlPanel            matlab.ui.container.Panel
        x_coord_entry                  matlab.ui.control.EditField
        y_coord_entry                  matlab.ui.control.EditField
        text54_2                       matlab.ui.control.Label
        text54_3                       matlab.ui.control.Label
        MoveXYmotorButton              matlab.ui.control.Button
        text54_4                       matlab.ui.control.Label
        text54_5                       matlab.ui.control.Label
        x_coord_text                   matlab.ui.control.Label
        y_coord_text                   matlab.ui.control.Label
        update_xymotor_coords          matlab.ui.control.Button
        text18                         matlab.ui.control.Label
        text19                         matlab.ui.control.Label
        ULx_coord                      matlab.ui.control.Label
        ULy_coord                      matlab.ui.control.Label
        LRy_coord                      matlab.ui.control.Label
        LRx_coord                      matlab.ui.control.Label
        text40                         matlab.ui.control.Label
        grid_size_display              matlab.ui.control.Label
        load_grid_button               matlab.ui.control.Button
        LP_angle_slider                matlab.ui.control.Slider
    end

    
    properties (Access = public)
    UL_x;
    UL_y;
    x_coord_motor=55;
    y_coord_motor=37.5;
    det_degree_settings;
    ill_degree_settings;
    det_RCA_LCA_settings;
    ill_RCA_LCA_settings;
    motor_x;
    motor_y;
    motor_ill;
    motor_det;
    save_folder;
    z_step = 1; % 1 micron
    saved_coordinates = cell(1,1);
    saved_coordinates_string = cell(1,1);
    saved_z_coordinates = zeros(1,3);
    saved_z_coordinates_string = cell(1,1);
    
    zstack_display = zeros(1200,1600,41);
    zstack_fm = zeros(41,1);
    i_zstack = 0;
    
    % FOV in microns calculated from calibration slide for each magnification
    mag_list = {'4X','10X','16X','20X','32X','40X','64X','60X','96X'};
    mags = [4 10 16 20 32 40 64 60 96]';
    pixels_per_100_microns = [54 136 210 270 420 542 840 815 1310]';
    FOV_pixels = [1200; 1600]; % [y, x]

    mag_index = 0;
    obj_string = '';
    
    overlap_for_stitching = 0.25; % percent
    
    stitching_perimeter = zeros(1,2);
    stitching_coordinates = 0;
    rectangular_stitching = 0;
    perimeter_stitching = 0;
    rectangular_stitch_folder = '';
    perimeter_stitch_folder = '';
    ULx = 0;
    ULy = 0;
    LRx = 0;
    LRy = 0;
    grid_size = 0;
    x_grid_mesh;
    y_grid_mesh;
    x_img_mesh;
    y_img_mesh;
    empty_tile = false;
    z_img_mesh = 0;
    FOV_microns_y;
    FOV_microns_x;
    FOV_microns_stitching_y;
    FOV_microns_stitching_x;
    mmc;
    saved_z_positions;
    end
    
    methods (Access = private)
        function [x_coord, y_coord] = read_motor_position(app)
            % read position of the motor
            x_coord = System.Decimal.ToDouble(app.motor_x.Position);
            y_coord = System.Decimal.ToDouble(app.motor_y.Position);      
        end
        
        function move_motor(app, x_coord, y_coord)
            % moves XY stage to preset position
            moveto(app, app.motor_x, x_coord);
            moveto(app, app.motor_y, y_coord);
            % update motor position text
            [x_coord, y_coord] = read_motor_position(app);
            app.x_coord_text.Text = num2str(x_coord);
            app.y_coord_text.Text = num2str(y_coord);
        end
                
        function moveto(app, motor, position)
            try
            workDone=motor.InitializeWaitHandler(); % Initialise Waithandler for timeout
            motor.MoveTo(position, workDone);       % Move devce to position via .NET interface
            motor.Wait(100000);              % Wait for move to finish
            catch
                disp('Unable to Move Device. Device will be homed')
                motor.Home(100000);
            end
        end
        function ask_to_insert_16x_lens(app)
            % Ask user to insert 1.6x lens into detection path
            d = dialog('Position',[300 300 400 100],'Name','Insert 1.6x Lens');
            d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                'Click "Continue" once the 1.6x lens has been inserted into the detection path.');
            d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                'Callback','delete(gcf)');
            uiwait(d);
        end
        function ask_user_mmc(app, display_text)
            if(strcmp(app.camera_status.Text, 'Micro-Manager') == 1)
                d = dialog('Position',[300 300 400 100],'Name','Exit Micro-Manager');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    display_text);
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                    'Callback','delete(gcf)');
                uiwait(d);
            end
        end
        

        function fig1 = plot_grid(app, x_min, x_max, y_min, y_max)
            % Plotting
            fig1 = figure('Position',[300 50 1200 900]);
            hold on;
            plot(app.x_img_mesh,app.y_img_mesh,'.','Color','red');
            plot(app.x_grid_mesh,app.y_grid_mesh,'Color','blue');
            plot(app.x_grid_mesh',app.y_grid_mesh','Color','blue');
            xlim([x_min-1.5 x_max+1.5]);
            ylim([y_min-1.5 y_max+1.5]);
            ax = gca;
            ax.YDir = 'reverse';
            ax.XDir = 'reverse';
            xlabel('X coordinates (mm)');
            ylabel('Y coordinates (mm)');
        end
        
        function load_camera_with_mmc(app)
            % Load camera through Micro-Manager core
            import mmcorej.*;
            app.mmc = CMMCore;
            app.mmc.loadSystemConfiguration ('C:\Program Files\Micro-Manager-2.0gamma\MMConfig_full_microscope_control.cfg'); % Config file with z-stage control
        
            app.camera_status.Text = 'MATLAB';
            app.mmc.setConfig('Objective',app.obj_string);
        end
        
        function input_save_filename(app)
            title = 'Enter the save name for this image set.';
            user_answer = inputdlg('Enter the save name for this image set:',...
                title,[1 length(title)+30]);
            save_string = user_answer{1,1};
            cd(app.save_folder);
            mkdir(save_string);
            app.rectangular_stitch_folder = [app.save_folder,'\',save_string];
            cd(app.rectangular_stitch_folder);
        end
        
        %disconnects all motors
        function disconnect_all_motors(app)
            app.motor_x.StopPolling();
            app.motor_x.Disconnect();
            
            app.motor_y.StopPolling();
            app.motor_y.Disconnect();
            
            app.motor_ill.StopPolling();
            app.motor_ill.Disconnect();
            
            app.motor_det.StopPolling();
            app.motor_det.Disconnect();
        end
        
        %opens window to choose magnification
        function [mag, mag_index] = choose_magnification(app)
            [app.mag_index,~] = listdlg('PromptString','Choose the magnification for stitching acquisition:','SelectionMode','Single',...
                'Name','Magnification Selection','ListSize',[300 200],'ListString',app.mag_list);
            mag = app.mags(app.mag_index,1);
            if(app.mag_index == 1 || app.mag_index == 2 || app.mag_index == 4 || app.mag_index == 6 || app.mag_index == 8)
                app.obj_string = app.mag_list{1,app.mag_index};
            else
                app.obj_string = app.mag_list{1,app.mag_index - 1};
            
                % Ask user to insert 1.6x lens into detection path
                d = dialog('Position',[300 300 400 100],'Name','Insert 1.6x Lens');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    'Click "Continue" once the 1.6x lens has been inserted into the detection path.');
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                    'Callback','delete(gcf)');
                uiwait(d);
            end
            mag_index = app.mag_index;
        end   
        
        %find z position, so the sample is in focus
        function [img, current_z] = autofocusing(app, correction_factor, exp_time, objective)
                % Variable correction_factor is used to correct for the slight displacement
                % in z between images collected with the 10X and 40X objectives
                app.mmc.setConfig('Objective', objective);
                current_z = app.mmc.getPosition;
            
                done = 0;
                fine_adj = 0;
                n = 0;
            
                disp('Finding best focus...');
                while(done == 0)
                    n = n + 1;
                    fprintf('Iteration #%d  ',n);
                    if(fine_adj == 0)
                        increment = 50;
                        fprintf('(coarse) \n');
                    else
                        increment = 5;
                        fprintf('(fine) \n');
                    end
            
                    if(n == 1)
                        % Nothing
            
                    elseif(n == 2)
                        direction = 1; % positive
                        current_z = current_z + direction*increment; % Move objective block up
                    else
                        % If most recent iteration was more in-focus than the second most recent iteration, continue in that direction
                        if(fm(n - 1,1) > fm(n - 2,1))
                            % Don't change direction
                        else
                            direction = -direction;
                        end
                        current_z = current_z + direction*increment;
                    end
            
            
                    app.mmc.setPosition(current_z);
                    pause(1);
                    img = snap_image(exp_time,app.mmc);
                    fm(n,1) = fmeasure(img,'BREN');
            
                    % After n = 3 iteration, the direction the z-stage needs to move has been determined
                    if(n > 3)
                        if(fm(n,1) < fm(n-1,1))
                            current_z = current_z - direction*increment;
                            if(fine_adj == 0)
                                fine_adj = 1;
                                n = 0;
                            else
                                done = 1;
                            end
                        end
                    end
            
                end
  
                current_z = round(app.mmc.getPosition - correction_factor); % Round to nearest integer
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to control_new_offline_copy (see VARARGIN)
            
            % Choose default command line output for control_new_offline_copy
            current_folder = pwd;
            addpath(current_folder);
            [~, bigio_index_end] = regexp(current_folder,'_Bigio');
            bigio_folder = current_folder(1:bigio_index_end);
            
            % Include path to MATLAB folder
            matlab_folder = [bigio_folder,'\MATLAB'];
            addpath(matlab_folder);
            
            % Include path to functions
            addpath([matlab_folder,'\functions']);
            % Load motorized rotational stages and settings
            for nnn = 1:1
                calibration_path{1,1} = [matlab_folder,'\Calibration_files\crossed_polarizer_settings'];
                addpath(calibration_path{1,1});
                ill_degree_settings = load('ill_5_degree_settings.mat'); %illumination
                det_degree_settings = load('det_5_degree_settings.mat'); %detection
                calibration_path{2,1} = [matlab_folder,'\Calibration_files\circular_analyzer_settings'];
                addpath(calibration_path{2,1});
                ill_RCA_LCA_settings = load('ill_RCA_LCA_settings.mat');
                det_RCA_LCA_settings = load('det_RCA_LCA_settings.mat');
                addpath('C:\Program Files\Micro-Manager-2.0gamma'); % Add path to MMCoreJ_wrap.dll
                % Also must copy path to MMCoreJ.jar to classpath.txt located in %matlabroot\toolbox\local
            
                field_names = fieldnames(det_degree_settings);
                app.det_degree_settings = det_degree_settings.(field_names{1,1});
                field_names = fieldnames(ill_degree_settings);
                app.ill_degree_settings = ill_degree_settings.(field_names{1,1});
                field_names = fieldnames(det_RCA_LCA_settings);
                app.det_RCA_LCA_settings = det_RCA_LCA_settings.(field_names{1,1});
                field_names = fieldnames(ill_RCA_LCA_settings);
                app.ill_RCA_LCA_settings = ill_RCA_LCA_settings.(field_names{1,1});
                clearvars field_names
            
                SN_ill = '55001048';
                SN_det = '55001045';
                SN_xy_controller = '73109054';
            
                %create motor objects and home devices
                [app.motor_x, app.motor_y] = motor_init(SN_xy_controller);
                app.motor_ill = rotat_stage_init(SN_ill);
                app.motor_det = rotat_stage_init(SN_det);

            end
            
            % Start in LCP-RCA configuration
            app.motor_ill.MoveTo(app.ill_RCA_LCA_settings(1,1), 60000);
            app.motor_det.MoveTo(app.det_RCA_LCA_settings(1,1), 60000);

            
            app.ill_pol_display.Text = sprintf('%.4f degrees', app.ill_RCA_LCA_settings(1,1));
            app.det_pol_display.Text = sprintf('%.4f degrees', app.det_RCA_LCA_settings(1,1)); 
            
            % Create time-stamped save folder each time an imaging session is started
            t = clock;
            new_folder_name = sprintf('%d_%02d_%02d_imaging_%02d_%02d_%02d',...
                t(1),t(2),t(3),t(4),t(5),round(t(6))); % t = [year, month, day, hour, minute, second]
            cd([bigio_folder,'\Images']);
            mkdir(new_folder_name);
            app.save_folder = [bigio_folder,'\Images\',new_folder_name];
            app.save_dir_user_entry.Value = app.save_folder;
            cd(app.save_folder);
            
            max_slider_val = 36;
            min_slider_val = 1;
            slider_steps1 = 1/(max_slider_val - min_slider_val);
            slider_steps2 = 3/(max_slider_val - min_slider_val);
            app.LP_angle_slider.Limits = [min_slider_val max_slider_val];
            
            app.LP_angle_slider.MajorTicks = min_slider_val:slider_steps1:max_slider_val;

            !C:\Program Files\Micro-Manager-2.0gamma\ImageJ.exe
            app.camera_status.Text = 'Micro-Manager';
            
            app.FOV_microns_y = 100*app.FOV_pixels(1,1)./app.pixels_per_100_microns; % First column --> y
            app.FOV_microns_x = 100*app.FOV_pixels(2,1)./app.pixels_per_100_microns; % Second column --> x
            app.FOV_microns_stitching_y = app.FOV_microns_y.*(1 - app.overlap_for_stitching);
            app.FOV_microns_stitching_x = app.FOV_microns_x.*(1 - app.overlap_for_stitching);
            disp('Initialization finished');
        end

        % Callback function
        function LP_angle_slider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to LP_angle_slider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            slider_position = get(app.LP_angle_slider,'Value');
            slider_position = round(slider_position);
            slider_setting = 5*(slider_position - 1);
            slider_setting_string = sprintf('%d degrees',slider_setting);
            set(app.LP_slider_display,'String',slider_setting_string);
            guidata(hObject,handles);
        end

        % Button pushed function: add_new_coords_button
        function add_new_coords_button_Callback(app, event)
            [x_coord, y_coord] = read_motor_position(app);
            
            if(isempty(app.saved_coordinates{1,1}) == 1)
                app.saved_coordinates{1,1}(1,1) = x_coord;
                app.saved_coordinates{1,1}(1,2) = y_coord;
                app.saved_coordinates_string{1,1} = sprintf('X = %0.4f ... Y = %0.4f',x_coord,y_coord);
                app.saved_z_coordinates(1,:) = zeros(1,3);
                app.saved_z_coordinates_string{1,1} = sprintf('Home = %04d ... Min = %04d ... Max = %04d',0,0,0);
            else
                app.saved_coordinates = vertcat(app.saved_coordinates,[x_coord,y_coord]);
                app.saved_coordinates_string = vertcat(app.saved_coordinates_string,sprintf('X = %0.4f ... Y = %0.4f',x_coord,y_coord));
                app.saved_z_coordinates = vertcat(app.saved_z_coordinates,zeros(1,3));
                app.saved_z_coordinates_string = vertcat(app.saved_z_coordinates_string,sprintf('Home = %04d ... Min = %04d ... Max = %04d',0,0,0));
            end
            app.coord_list.Items = app.saved_coordinates_string;
            app.z_coord_list.Items = app.saved_z_coordinates_string;
        end

        % Button pushed function: bf_button_lcp_lca
        function bf_button_lcp_lca_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to bf_button_lcp_lca (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.motor_ill.SetAbsMovePos(0,app.ill_RCA_LCA_settings(1,1));
            app.motor_det.SetAbsMovePos(0,app.det_RCA_LCA_settings(2,1));
            app.motor_ill.MoveAbsolute(0,0);
            app.motor_det.MoveAbsolute(0,0);
            set(app.ill_pol_display,'String',sprintf('%.4f degrees',app.ill_RCA_LCA_settings(1,1)));
            set(app.det_pol_display,'String',sprintf('%.4f degrees',app.det_RCA_LCA_settings(2,1)));
            moving = 1;
            timeout = 20;
            current_time = clock;
            while(moving == 1 && etime(clock,current_time) < timeout)
                ill_status = app.motor_ill.GetStatusBits_Bits(0);
                det_status = app.motor_det.GetStatusBits_Bits(0);
                moving = are_K10CR1s_moving(ill_status,det_status);
            end
            guidata(hObject,handles);
        end

        % Button pushed function: bf_button_rcp_rca
        function bf_button_rcp_rca_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to bf_button_rcp_rca (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.motor_ill.SetAbsMovePos(0,app.ill_RCA_LCA_settings(2,1));
            app.motor_det.SetAbsMovePos(0,app.det_RCA_LCA_settings(1,1));
            set(app.ill_pol_display,'String',sprintf('%.4f degrees',app.ill_RCA_LCA_settings(2,1)));
            set(app.det_pol_display,'String',sprintf('%.4f degrees',app.det_RCA_LCA_settings(1,1)));
            app.motor_ill.MoveAbsolute(0,0);
            app.motor_det.MoveAbsolute(0,0);
            moving = 1;
            timeout = 20;
            current_time = clock;
            while(moving == 1 && etime(clock,current_time) < timeout)
                ill_status = app.motor_ill.GetStatusBits_Bits(0);
                det_status = app.motor_det.GetStatusBits_Bits(0);
                moving = are_K10CR1s_moving(ill_status,det_status);
            end
            guidata(hObject,handles);
        end

        % Button pushed function: browse_button
        function browse_button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to browse_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            user_selection = uigetdir(app.save_folder);
            
            % Create time-stamped save folder
            t = clock;
            new_folder_name = sprintf('%d_%02d_%02d_imaging_%02d_%02d_%02d',...
                t(1),t(2),t(3),t(4),t(5),round(t(6))); % t = [year, month, day, hour, minute, second]
            cd(user_selection);
            mkdir(new_folder_name);
            app.save_folder = [user_selection,'\',new_folder_name];
            set(app.save_dir_user_entry,'String',app.save_folder);
            guidata(hObject, handles);
        end

        % Button pushed function: calculate_grid_button
        function calculate_grid_button_Callback(app, event)
            if(all([app.ULx, app.ULy, app.LRx, app.LRy,]) == 1)
                % If all XY stitching coordinates have been entered
                app.rectangular_stitching = 1;
            
                % Choose magnification
                [mag, app.mag_index] = choose_magnification(app);
            
                % Compute actual xy locations for rectangular grid acquisition
                x_min = app.LRx;
                x_max = app.ULx;
                x_step = app.FOV_microns_stitching_x(app.mag_index)/1000; % convert FOV in microns to mm
                x_max = x_max + x_step/2; % increase to include the point itself
            
                y_min = app.ULy;
                y_max = app.LRy; 
                y_step = app.FOV_microns_stitching_y(app.mag_index)/1000; % convert FOV in microns to mm
                y_max = y_max + y_step/2;  % increase to include the point itself
            
                x_img = x_min:x_step:x_max;
                x_plot_grid = (x_min-x_step/2):x_step:(x_max+x_step/2); % Step is divided by 2 to center frame over boundary
            
                y_img = y_min:y_step:y_max;
                y_plot_grid = (y_min-y_step/2):y_step:(y_max+y_step/2); % Step is divided by 2 to center frame over boundary
            
                % x_img and y_img - center points of each image
                [app.x_grid_mesh,app.y_grid_mesh] = meshgrid(x_plot_grid,y_plot_grid);
                [app.x_img_mesh,app.y_img_mesh] = meshgrid(x_img,y_img);
                
                %grid plotting
                fig1 = plot_grid(app, x_min, x_max, y_min, y_max);
                input_save_filename(app);
                     
                app.LRx_coord.Text = num2str(app.LRx);
                app.LRy_coord.Text = num2str(app.LRy);
                grid_size_x = length(x_img);
                grid_size_y = length(y_img);
                app.grid_size_display.Text = sprintf('%d x %d',grid_size_x,grid_size_y);
            
                %save coordinates
                x_img_mesh = app.x_img_mesh;
                y_img_mesh = app.y_img_mesh;
                x_grid_mesh = app.x_grid_mesh;
                y_grid_mesh = app.y_grid_mesh;
                rect = app.rectangular_stitching;
                save('image_xy_coords.mat','x_img_mesh','y_img_mesh','x_grid_mesh','y_grid_mesh','mag','rect');
                disp('XY coordinates for stitching acquisition saved and ready.');
                app.stitching_acquisition_display.Text = 'Ready';
            else
                % error
                % Missing XY stitching coordinates (UL and LR)
                d = dialog('Position',[300 300 400 100],'Name','Coordinates or Grid Size Missing');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    'The upper left and lower right coordinates have not been set. Close this dialog box and set them.');
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Close',...
                    'Callback','delete(gcf)');
                uiwait(d);
                return;
            end
            close(fig1);
            
        end

        % Button pushed function: calibrate_z_10x_button
        function calibrate_z_10x_button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to calibrate_z_10x_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if(strcmp(get(app.camera_status,'String'),'Micro-Manager') == 1)
                d = dialog('Position',[300 300 400 100],'Name','Exit Micro-Manager');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    'Click "Continue" once Micro-Manager has been closed and the camera can be loaded into MATLAB.');
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                    'Callback','delete(gcf)');
                uiwait(d);
            end
            
            % Load camera through Micro-Manager core
            import mmcorej.*;
            app.mmc = CMMCore;
            app.mmc.loadSystemConfiguration ('C:\Program Files\Micro-Manager-2.0gamma\MMConfig_full_microscope_control.cfg'); % Config file with z-stage control
            
            set(app.camera_status,'String','MATLAB');
            
            % Loop through all ROIs in the Coordinate List
            for i = 1:size(app.saved_coordinates,1)
                app.h_y.SetAbsMovePos(0,app.saved_coordinates{i,1}(1,2));
                app.h_y.MoveAbsolute(0,0);
                app.h_x.SetAbsMovePos(0,app.saved_coordinates{i,1}(1,1));
                app.h_x.MoveAbsolute(0,0);
                pause(1);
                done = 0;
                fine_adj = 0;
                n = 0;
            
                while(done == 0)
                    n = n + 1;
                    fprintf('Iteration #%d  ',n);
                    if(fine_adj == 0)
                        increment = 50;
                        fprintf('(coarse) \n');
                    else
                        increment = 5;
                        fprintf('(fine) \n');
                    end
            
                    if(n == 1)
                        current_z = app.z_home; % First reference
                        app.mmc.setPosition(current_z);
                        pause(1);
                        if(fine_adj == 0)
                            exp_time = 40; % ms
                            img = snap_image(exp_time,app.mmc);
            
                            % If any pixels are saturated, lower exposure time
                            while(max(max(img)) >= 2^16 - 1)
                                exp_time = exp_time/2; % exp = 40 --> 20 --> 10 --> 5 --> 2.5
                                img = snap_image(exp_time,app.mmc);
                            end
                        end
            
                    elseif(n == 2)
                        direction = 1; % positive
                        current_z = current_z + direction*increment; % Move objective block up
                    else
                        % If most recent iteration was more in-focus than the second most recent iteration, continue in that direction
                        if(fm(n - 1,1) > fm(n - 2,1))
                            % Don't change direction
                        else
                            direction = -direction;
                        end
                        current_z = current_z + direction*increment;
                    end
            
            
                    app.mmc.setPosition(current_z);
                    pause(1);
                    img = snap_image(exp_time,app.mmc);
                    fm(n,1) = fmeasure(img,'BREN');
            
                    % After n = 3 iteration, the direction the z-stage needs to move has been determined
                    if(n > 3)
                        if(fm(n,1) < fm(n-1,1))
                            current_z = current_z - direction*increment;
                            app.saved_z_coordinates(i,1) = current_z;
                            if(fine_adj == 0)
                                fine_adj = 1;
                                n = 0;
                            else
                                done = 1;
                            end
                        end
                    end
            
                end
                app.saved_z_coordinates_string{i,1} = sprintf('Home = %04d ... Min = %04d ... Max = %04d',app.saved_z_coordinates(i,1),0,0);
                figure;
                imshow(img);
            end
            
            set(app.z_coord_list,'String',app.saved_z_coordinates_string);
            
            app.mmc.reset();
            guidata(hObject, handles);
        end

        % Button pushed function: calibrate_z_40x_button
        function calibrate_z_40x_button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to calibrate_z_40x_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if(strcmp(get(app.camera_status,'String'),'Micro-Manager') == 1)
                d = dialog('Position',[300 300 400 100],'Name','Exit Micro-Manager');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    'Click "Continue" once Micro-Manager has been closed and the camera can be loaded into MATLAB.');
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                    'Callback','delete(gcf)');
                uiwait(d);
            end
            
            % Load camera through Micro-Manager core
            import mmcorej.*;
            app.mmc = CMMCore;
            app.mmc.loadSystemConfiguration ('C:\Program Files\Micro-Manager-2.0gamma\MMConfig_full_microscope_control.cfg'); % Config file with z-stage control
            
            set(app.camera_status,'String','MATLAB');
            exp_time = str2double(get(app.zstack_exp_time_user_entry,'String'));
            
            % Loop through all ROIs in the Coordinate List
            fm_max_indices = zeros(size(app.saved_coordinates,1),1);
            for i = 1:size(app.saved_coordinates,1)
                app.h_y.SetAbsMovePos(0,app.saved_coordinates{i,1}(1,2));
                app.h_y.MoveAbsolute(0,0);
                app.h_x.SetAbsMovePos(0,app.saved_coordinates{i,1}(1,1));
                app.h_x.MoveAbsolute(0,0);
                pause(1);
                z_range = 80; % Check over 200 microns of objective translation in z
                z_min = app.saved_z_coordinates(i,1) - z_range/2;
                z_max = app.saved_z_coordinates(i,1) + z_range/2;
                z_range_values = z_min:2:z_max;
                n = 0;
                for z = z_min:2:z_max
                    n = n + 1;
                    app.mmc.setPosition(z);
                    pause(0.5);
                    app.zstack_display(:,:,n) = double(snap_image(exp_time,app.mmc));
                    app.zstack_fm(n,1) = fmeasure(app.zstack_display(:,:,n),'BREN');
                end
                [~,fm_max_indices(i,1)] = max(app.zstack_fm);
            
                app.saved_z_coordinates(i,2) = z_range_values(1,fm_max_indices(i,1)) - 20; % column 2 --> minimum z value
                app.saved_z_coordinates(i,3) = z_range_values(1,fm_max_indices(i,1)) + 20; % column 3 --> maximum z value
                app.saved_z_coordinates_string{i,1} = sprintf('Home = %04d ... Min = %04d ... Max = %04d',...
                    app.saved_z_coordinates(i,1),app.saved_z_coordinates(i,2),app.saved_z_coordinates(i,3));
            
                figure;
                imshow3D(app.zstack_display);
                figure;
                plot(1:size(app.zstack_fm,1),app.zstack_fm,'o');
            end
            
            set(app.z_coord_list,'String',app.saved_z_coordinates_string);
            
            app.mmc.reset();
        end

        % Button pushed function: clear_coords_button
        function clear_coords_button_Callback(app, event)
            app.saved_coordinates = cell(1,1);
            app.saved_coordinates_string = cell(1,1);
            app.coord_list.Items =  {'Save/Load Coordinates'};
            app.saved_z_coordinates = zeros(1,3);
            app.saved_z_coordinates_string = cell(1,1);
            app.z_coord_list.Items =  {'Save/Load Coordinates'};
        end

        % Button pushed function: custom_acquisition_2_button
        function custom_acquisition_2_button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to custom_acquisition_2_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Custom Acquisition 2 = Acquiring individual images at different ROIs over time
            
            if(strcmp(get(app.camera_status,'String'),'Micro-Manager') == 1)
                d = dialog('Position',[300 300 400 100],'Name','Exit Micro-Manager');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    'Click "Continue" once Micro-Manager has been closed and the camera can be loaded into MATLAB.');
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                    'Callback','delete(gcf)');
                uiwait(d);
            end
            
            % Load camera through Micro-Manager core
            import mmcorej.*;
            app.mmc = CMMCore;
            app.mmc.loadSystemConfiguration ('C:\Program Files\Micro-Manager-2.0gamma\MMConfig_full_microscope_control.cfg'); % Config file with z-stage control
            
            set(app.camera_status,'String','MATLAB');
            cd(app.save_folder);
            mkdir('Custom Acquisition');
            app.exp_times = inputdlg({'Exposure time 1 (ms):','Exposure time 2 (ms):','Exposure time 3 (ms):'},'Enter Exposure Times',[1,30],{'5','25','50'});
            img = cell(size(app.exp_times,1),1); % One image taken at each exposure time
            
            num_t_points = 5000;
            seconds_stamp_full = zeros(num_t_points,1);
            num_ROIs = size(app.saved_coordinates,1);
            num_exp = size(app.exp_times,1);
            ROI_string = cell(num_ROIs,1);
            exp_string = cell(num_exp,1);
            zfocus_ROI = zeros(num_ROIs,1);
            
            for n = 1:num_ROIs
                cd([app.save_folder,'\Custom Acquisition']);
                zfocus_ROI(n,1) = (app.saved_z_coordinates(n,2) + app.saved_z_coordinates(n,3))/2; % Go to middle of the 40X bounds
                ROI_string{n,1} = sprintf('ROI %d',n);
                mkdir(ROI_string{n,1});
                cd([app.save_folder,'\Custom Acquisition\',ROI_string{n,1}]);
                for m = 1:num_exp
                    exp_string{m,1} = sprintf('exp %d',m);
                    mkdir(exp_string{m,1});
                end
            end
            
            for i = 1:num_t_points
            
                % Using one time designation for all ROIs and exposures captured per time point
                t = clock; % t = [year, month, day, hour, minute, second]
                seconds_stamp = 3600*t(4) + 60*t(5) + round(t(6));
                seconds_stamp_full(i,1) = seconds_stamp;
            
                for n_ROI = 1:num_ROIs
                    img{1,1} = zeros(1200,1600);
                    img{2,1} = zeros(1200,1600);
                    img{3,1} = zeros(1200,1600);
            
                    app.h_y.SetAbsMovePos(0,app.saved_coordinates{n_ROI,1}(1,2));
                    app.h_y.MoveAbsolute(0,0);
                    app.h_x.SetAbsMovePos(0,app.saved_coordinates{n_ROI,1}(1,1));
                    app.h_x.MoveAbsolute(0,0);
            
            
                    cd([app.save_folder,'\Custom Acquisition\',ROI_string{n_ROI,1}]);
            
                    for i_exp = 1:3
                        if(i_exp == 1)
                            app.mmc.setPosition(zfocus_ROI(n_ROI,1));
                            pause(0.5);
                        end
            
                        img{i_exp,1} = snap_image(str2double(app.exp_times{i_exp,1}),app.mmc);
                        cd([app.save_folder,'\Custom Acquisition\',ROI_string{n_ROI,1},'\',exp_string{i_exp,1}]);
                        save_name = sprintf('img_s%d.tif',seconds_stamp);
                        imwrite(img{i_exp,1},save_name);
                    end
            
                end
            end
            
            
            guidata(hObject, handles);
        end

        % Button pushed function: custom_acquisition_button
        function custom_acquisition_button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to custom_acquisition_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Custom Acquisition 1 = Acquiring zstacks at different ROIs over time
            
            if(strcmp(get(app.camera_status,'String'),'Micro-Manager') == 1)
                d = dialog('Position',[300 300 400 100],'Name','Exit Micro-Manager');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    'Click "Continue" once Micro-Manager has been closed and the camera can be loaded into MATLAB.');
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                    'Callback','delete(gcf)');
                uiwait(d);
            end
            
            % Load camera through Micro-Manager core
            import mmcorej.*;
            app.mmc = CMMCore;
            app.mmc.loadSystemConfiguration ('C:\Program Files\Micro-Manager-2.0gamma\MMConfig_full_microscope_control.cfg'); % Config file with z-stage control
            
            set(app.camera_status,'String','MATLAB');
            cd(app.save_folder);
            mkdir('Custom Acquisition');
            app.exp_times = inputdlg({'Exposure time 1 (ms):','Exposure time 2 (ms):','Exposure time 3 (ms):'},'Enter Exposure Times',[1,30],{'5','25','50'});
            zstacks = cell(3,1);
            minute_stamp = zeros(20,1);
            num_zplanes = 5;
            num_t_points = 40;
            num_ROIs = size(app.saved_coordinates,1);
            ROI_string = cell(num_ROIs,1);
            for i = 1:num_t_points
                for n_ROI = 1:num_ROIs
                    zstacks{1,1} = zeros(1200,1600,num_zplanes);
                    zstacks{2,1} = zeros(1200,1600,num_zplanes);
                    zstacks{3,1} = zeros(1200,1600,num_zplanes);
            
                    app.h_y.SetAbsMovePos(0,app.saved_coordinates{n_ROI,1}(1,2));
                    app.h_y.MoveAbsolute(0,0);
                    app.h_x.SetAbsMovePos(0,app.saved_coordinates{n_ROI,1}(1,1));
                    app.h_x.MoveAbsolute(0,0);
            
                    if(i == 1)
                        cd([app.save_folder,'\Custom Acquisition']);
                        ROI_string{n_ROI,1} = sprintf('ROI_%d',n_ROI);
                        mkdir(ROI_string{n_ROI,1});
                    else
            %             % For subsequent iterations, wait until 10 minutes have gone by and re-image all ROIs
            %             t_interval = 10; % minutes
            %             while(minute_stamp(i,1) < minute_stamp(1,1) + (i*t_interval))
            %                 pause(30); % Check time ~ every 30 seconds
            %                 t = clock; % t = [year, month, day, hour, minute, second]
            %                 minute_stamp(i,1) = 60*t(1,4) + t(1,5);
            %             end
                    end
            
                    t = clock; % t = [year, month, day, hour, minute, second]
                    new_folder_name = sprintf('time_%02d_%02d_%02d',...
                        t(4),t(5),round(t(6)));
                    cd([app.save_folder,'\Custom Acquisition\',ROI_string{n_ROI,1}]);
                    mkdir(new_folder_name);
                    cd([app.save_folder,'\Custom Acquisition\',ROI_string{n_ROI,1},'\',new_folder_name]);
                    z_i = 0;
                    for z = app.saved_z_coordinates(n_ROI,2):1:app.saved_z_coordinates(n_ROI,3)
                        z_i = z_i + 1;
                        for i_exp = 1:3
                            if(i_exp == 1)
                                app.mmc.setPosition(z);
                                pause(0.2);
                            end
                            zstacks{i_exp,1}(:,:,z_i) = snap_image(str2double(app.exp_times{i_exp,1}),app.mmc);
                        end
                    end
                    save('zstacks.mat','zstacks','-v7.3');
                end
            end
            guidata(hObject, handles);
        end

        % Button pushed function: det_pol_rotate_button
        function det_pol_rotate_button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to det_pol_rotate_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(app.det_pol_display,'String','Rotating...');
            angle_string = get(app.det_pol_user_entry,'String');
            angle_val = str2double(angle_string);
            app.motor_det.SetAbsMovePos(0,angle_val);
            app.motor_det.MoveAbsolute(0,0);
            moving = 1;
            timeout = 10;
            current_time = clock;
            while(moving == 1 && etime(clock,current_time) < timeout)
                ill_status = app.motor_ill.GetStatusBits_Bits(0);
                det_status = app.motor_det.GetStatusBits_Bits(0);
                moving = are_K10CR1s_moving(ill_status,det_status);
            end
            set(app.det_pol_display,'String',sprintf('%.4f degrees',angle_val));
            guidata(hObject,handles);
        end

        % Button pushed function: determine_z_focus_grid
        function determine_z_focus_grid_Callback(app, event)
            if isempty(app.x_img_mesh)
                % error
                d = dialog('Position',[300 300 400 100],'Name','Grid Information is Missing');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    'Imaging coordinates have not been set. Close this dialog box and set them.');
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Close',...
                    'Callback','delete(gcf)');
                uiwait(d);
                return;
            else
                % Plotting
                fig1 = figure('Position',[300 50 1200 900]);
                hold on;
                plot(app.x_img_mesh,app.y_img_mesh,'.','Color','red');
                plot(app.x_grid_mesh,app.y_grid_mesh,'Color','blue');
                plot(app.x_grid_mesh',app.y_grid_mesh','Color','blue');
                x_min = min(app.x_img_mesh(1,:));
                x_max = max(app.x_img_mesh(1,:));
                y_min = min(app.y_img_mesh(:,1));
                y_max = max(app.y_img_mesh(:,1));
                xlim([x_min-1.5 x_max+1.5]);
                ylim([y_min-1.5 y_max+1.5]);
                ax = gca;
                ax.YDir = 'reverse';
                ax.XDir = 'reverse';
                xlabel('X coordinates (mm)');
                ylabel('Y coordinates (mm)');
                title('Select Upper Left sample tile for focusing.'); % Start with upper left
                
                % Ask user to switch to correct objective and view with Micro-Manager
                d = dialog('Position',[300 300 400 100],'Name','Use Micro-Manager for Live View');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    ['Move ',app.obj_string,' into place and view sample with Micro-Manager.']);
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                    'Callback','delete(gcf)');
                uiwait(d);
                
                % Manually select tiles, go to selected coordinates, and ask user to designate if empty
                z_focus_corners = zeros(3,1);
                corner_indices = zeros(3,2);
                fprintf('First, select Upper Left sample tile for focusing.\n');
                count = 0;
                while count < 3
                    %read mouse position
                    [x,y] = ginput(1);
                    %end function by closing interface
                    if(isempty(x) == true && isempty(y) == true)
                        break;
                    else
                        % calculating based on where you click what lines of rectangle to
                        % highlight
                        [~,x_ind] = min(abs(x*ones(1,length(app.x_img_mesh(1,:))) - app.x_img_mesh(1,:)));
                        [~,y_ind] = min(abs(y*ones(length(app.y_img_mesh(:,1)),1) - app.y_img_mesh(:,1)));
                        closed_rect = [app.x_grid_mesh(y_ind,x_ind) app.y_grid_mesh(y_ind,x_ind);...
                            app.x_grid_mesh(y_ind,x_ind) app.y_grid_mesh(y_ind+1,x_ind+1);...
                            app.x_grid_mesh(y_ind+1,x_ind+1) app.y_grid_mesh(y_ind+1,x_ind+1);...
                            app.x_grid_mesh(y_ind+1,x_ind+1) app.y_grid_mesh(y_ind,x_ind);...
                            app.x_grid_mesh(y_ind,x_ind) app.y_grid_mesh(y_ind,x_ind)];
                        tile_highlight1 = plot(closed_rect(:,1),closed_rect(:,2),'Color','red','LineWidth',5);
                
                        % Go to selected XY coordinates
                        y_coord = app.y_img_mesh(y_ind,1);
                        x_coord = app.x_img_mesh(1,x_ind);
                        move_motor(app, x_coord, y_coord);
                
                        % Ask user to confirm that they would like to use this tile for determining objective focus for the current corner
                        use_tile_ans = questdlg('Use this tile for determining Z-focus position in this corner of the sample?',...
                            'Choose corner tile for determining Z-focus','Yes','No - choose a new tile','Yes');
                        if(strcmp(use_tile_ans,'Yes') == 1)
                            % Tell user to close Micro-Manager to get objective Z-position
                            d = dialog('Position',[300 300 400 100],'Name','Bring sample into focus and close Micro-Manager to get objective Z-position');
                            d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                                'Close Micro-Manager before clicking "Continue".');
                            d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                                'Callback','delete(gcf)');
                            %waits until you click continue
                            uiwait(d);
                
                            % Load microscope hardware through Micro-Manager core and get objective position
                            import mmcorej.*;
                            app.mmc = CMMCore;
                            app.mmc.loadSystemConfiguration ('C:\Program Files\Micro-Manager-2.0gamma\MMConfig_full_microscope_control.cfg'); % Config file with z-stage control
                            app.camera_status.Text = 'MATLAB';
                
                            count = count + 1;
                            z_focus_corners(count,1) = app.mmc.getPosition;
                            corner_indices(count,:) = [y_ind,x_ind];
                            app.mmc.reset();
                            if(count < 3)
                                !C:\Program Files\Micro-Manager-2.0gamma\ImageJ.exe
                                app.camera_status.Text = 'Micro-Manager';
                            end
                
                            % Display recorded z-focus and indicate next corner to focus
                            if(count == 1)
                                P1 = [x_coord, y_coord, z_focus_corners(count,1)];
                                fprintf('Upper Left Z-focus = %f microns\n',z_focus_corners(count,1));
                                fprintf('Next, select Upper Right sample tile for focusing.\n');
                                figure(1);
                                title('Select Upper Right sample tile for focusing.'); % UL --> UR
                            elseif(count == 2)
                                P2 = [x_coord, y_coord, z_focus_corners(count,1)];
                                fprintf('Upper Right Z-focus = %f microns\n',z_focus_corners(count,1));
                                fprintf('Next, select Lower Left sample tile for focusing.\n');
                                figure(1);
                                title('Select Lower Left sample tile for focusing.'); % UR --> LL
                            elseif(count == 3)
                                P3 = [x_coord, y_coord, z_focus_corners(count,1)];
                                fprintf('Lower Left Z-focus = %f microns\n',z_focus_corners(count,1));
                                fprintf('Next, select Lower Right sample tile for focusing.\n');
                                figure(1);
                                title('Select Lower Right sample tile for focusing.'); % LL --> LR
                            end
                            delete(tile_highlight1);
                            patch(closed_rect(:,1),closed_rect(:,2),'green');
                
                        elseif(strcmp(use_tile_ans,'No - choose a new tile') == 1)
                            disp('Choose a new tile to use for focusing.');
                        end
                    end
                end
                
                % Calculate Z-focus values across the entire set of tiles
                % use 3 selected points on the plane to find plane equation
                N = cross(P1-P2,P1-P3); % find normal vector
                N = N./norm(N); % normalaze the value
                A = N(1); B=N(2); C=N(3); % define coordinates of normal vector
                x0 = P1(1); y0 = P1(2); z0 = P1(3); % define coordinated of the origin
                x = app.x_img_mesh; %set values for x,y
                y = app.y_img_mesh;
                z_img_mesh = (-A.*(x-x0)-B.*(y-y0)+C.*z0)./C; % find z values with plane equation
                
                app.z_img_mesh = z_img_mesh;
                cd(app.rectangular_stitch_folder);
                
                save('image_z_coords.mat','z_img_mesh','-v7.3');
                disp('Saved Z-focus values to image_z_coords.mat');
                close(fig1);
            end

        end

        % Button pushed function: go_LR_button
        function go_LR_button_Callback(app, event)
            % move motor to lower right position of ROI
            move_motor(app, app.LRx, app.LRy)
        end

        % Button pushed function: go_UL_button
        function go_UL_button_Callback(app, event)
            % move motor to upper left position of ROI
            move_motor(app, app.ULx, app.ULy)
        end

        % Button pushed function: go_to_coords_button
        function go_to_coords_button_Callback(app, event)
            % find index of selected coordinates in the boxlist 
            [~, selected_index] = ismember(app.coord_list.Value, app.coord_list.Items);
            %find value of selected coordinates
            x_coord = app.saved_coordinates{selected_index,1}(1,1);
            y_coord = app.saved_coordinates{selected_index,1}(1,2);
            % move motor to selected position
            move_motor(app, x_coord, y_coord);
        end

        % Button pushed function: ill_pol_rotate_button
        function ill_pol_rotate_button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ill_pol_rotate_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(app.ill_pol_display,'String','Rotating...');
            angle_string = get(app.ill_pol_user_entry,'String');
            angle_val = str2double(angle_string);
            app.motor_ill.SetAbsMovePos(0,angle_val);
            app.motor_ill.MoveAbsolute(0,0);
            moving = 1;
            timeout = 10;
            current_time = clock;
            while(moving == 1 && etime(clock,current_time) < timeout)
                ill_status = app.motor_ill.GetStatusBits_Bits(0);
                det_status = app.motor_det.GetStatusBits_Bits(0);
                moving = are_K10CR1s_moving(ill_status,det_status);
            end
            set(app.ill_pol_display,'String',sprintf('%.4f degrees',angle_val));
            guidata(hObject,handles);
        end

        % Button pushed function: lcp_rca_button
        function lcp_rca_button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to lcp_rca_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.motor_ill.SetAbsMovePos(0,app.ill_RCA_LCA_settings(1,1));
            app.motor_det.SetAbsMovePos(0,app.det_RCA_LCA_settings(1,1));
            app.motor_ill.MoveAbsolute(0,0);
            app.motor_det.MoveAbsolute(0,0);
            set(app.ill_pol_display,'String',sprintf('%.4f degrees',app.ill_RCA_LCA_settings(1,1)));
            set(app.det_pol_display,'String',sprintf('%.4f degrees',app.det_RCA_LCA_settings(1,1)));
            moving = 1;
            timeout = 20;
            current_time = clock;
            while(moving == 1 && etime(clock,current_time) < timeout)
                ill_status = app.motor_ill.GetStatusBits_Bits(0);
                det_status = app.motor_det.GetStatusBits_Bits(0);
                moving = are_K10CR1s_moving(ill_status,det_status);
            end
            guidata(hObject,handles);
        end

        % Button pushed function: load_coords_button
        function load_coords_button_Callback(app, event)
            try
                [file_name,file_path] = uigetfile(app.save_folder,'.*mat');
                cd(file_path);
                loaded_coordinates = load(file_name);
                loaded_coordinates = loaded_coordinates.('saved_coordinates');
                app.saved_coordinates = loaded_coordinates;
                for i = 1:size(app.saved_coordinates,1)
                    app.saved_coordinates_string{i,1} = sprintf('X = %0.4f ... Y = %0.4f',app.saved_coordinates{i,1}(1,1),app.saved_coordinates{i,1}(1,2));
                    app.saved_z_coordinates(i,:) = zeros(1,3);
                    app.saved_z_coordinates_string{i,1} = sprintf('Home = %04d ... Min = %04d ... Max = %04d',0,0,0);
                end
                app.coord_list.Items = app.saved_coordinates_string;
                app.z_coord_list.Items = app.saved_z_coordinates_string;
            catch
                disp('Can`t do it')
            end
        end

        % Button pushed function: load_grid_button
        function load_grid_button_Callback(app, event)

            % Ask user if they are loading an xy or z grid
            use_grid_ans = questdlg('Are you loading grid of xy or z positions?',...
                'XY or Z Positions?','XY Positions','Z Positions','XY Positions');
            
            [file_name,file_path] = uigetfile(app.save_folder,'.*mat');
            cd(file_path);
            loaded_grid = load(file_name);
            
            if(strcmp(use_grid_ans,'XY Positions') == 1)
                app.x_img_mesh = loaded_grid.('x_img_mesh');
                app.y_img_mesh = loaded_grid.('y_img_mesh');
                app.x_grid_mesh = loaded_grid.('x_grid_mesh');
                app.y_grid_mesh = loaded_grid.('y_grid_mesh');
                mag = loaded_grid.('mag');
                app.rectangular_stitching = loaded_grid.('rect');
                app.mag_index = find(app.mags == mag);
                
                % ask to insert 1.6x lens for certain magnifications
                if(app.mag_index == 1 || app.mag_index == 2 || app.mag_index == 4 || app.mag_index == 6 || app.mag_index == 8)
                    app.obj_string = app.mag_list{1,app.mag_index};
                else
                    app.obj_string = app.mag_list{1,app.mag_index - 1};
            
                    % Ask user to insert 1.6x lens into detection path
                    ask_to_insert_16x_lens(app);
                end
                % show corner`s coordinates and grid size
                app.ULx_coord.Text = num2str(max(app.x_img_mesh));
                app.ULy_coord.Text = num2str(min(app.y_img_mesh));
                app.LRx_coord.Text = num2str(min(app.x_img_mesh));
                app.LRy_coord.Text = num2str(max(app.y_img_mesh));
                num_images_row = size(app.x_img_mesh,1);
                num_images_col = size(app.x_img_mesh,2);
                grid_size_string = sprintf('%d x %d',num_images_col,num_images_row);
                disp(['Loaded XY grid with dimensions: ',grid_size_string]);
                app.grid_size_display.Text = grid_size_string;
                
            elseif(strcmp(use_grid_ans,'Z Positions') == 1)
                app.z_img_mesh = loaded_grid.('z_img_mesh');
                num_images_row = size(app.z_img_mesh,1);
                num_images_col = size(app.z_img_mesh,2);
                grid_size_string = sprintf('%d x %d',num_images_col,num_images_row);
                disp(['Loaded Z grid with dimensions: ',grid_size_string]);
            end
            
            app.stitching_acquisition_display.Text = 'Ready';
            input_save_filename(app);

 
        end

        % Callback function
        function load_stitching_perimeter_button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to load_stitching_perimeter_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [file_name,file_path] = uigetfile(app.save_folder,'.*mat');
            cd(file_path);
            loaded_coordinates = load(file_name);
            loaded_coordinates = loaded_coordinates.('xy_coords');
            set(app.stitching_perimeter_display,'String','Set');
            app.stitching_perimeter = loaded_coordinates;
            disp('Coordinates for perimeter stitching loaded.');
            title = 'Enter the save name for this image set.';
            user_answer = inputdlg('Enter the save name for this image set:',...
                title,[1 length(title)+30]);
            save_string = user_answer{1,1};
            cd(app.save_folder);
            mkdir(save_string);
            app.perimeter_stitch_folder = [app.save_folder,'\',save_string];
            cd(app.perimeter_stitch_folder);
            guidata(hObject, handles);
        end

        % Button pushed function: mm_button
        function mm_button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to mm_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if(strcmp(get(app.camera_status,'String'),'MATLAB') == 1)
                app.mmc.reset();
                % Open Micro-Manager Launcher
                !C:\Program Files\Micro-Manager-2.0gamma\ImageJ.exe
                set(app.camera_status,'String','Micro-Manager');
            end
            guidata(hObject,handles);
        end

        % Button pushed function: rLP_lca_button
        function rLP_lca_button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to rLP_lca_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if(strcmp(get(app.camera_status,'String'),'Micro-Manager') == 1)
                d = dialog('Position',[300 300 400 100],'Name','Exit Micro-Manager');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    'Click "Continue" once Micro-Manager has been closed and the camera can be loaded into MATLAB.');
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                    'Callback','delete(gcf)');
                uiwait(d);
            end
            
            % Load camera through Micro-Manager core
            import mmcorej.*;
            app.mmc = CMMCore;
            app.mmc.loadSystemConfiguration ('C:\Program Files\Micro-Manager-2.0gamma\MMConfig_QCam_edit.cfg');
            
            set(app.camera_status,'String','MATLAB');
            
            % Request user input for exposure time
            title = 'Enter the exposure time for each image.';
            user_answer = inputdlg('Exposure time (ms):',title,[1 length(title)+30]);
            exposure_time = str2double(user_answer{1,1});
            cd(app.save_folder);
            
            title = 'Enter the name to save the image set under.';
            user_answer = inputdlg('Enter the save name for the sample image set (without any extensions):',...
                title,[1 length(title)+30]);
            
            save_string = user_answer{1,1};
            new_foldername = [save_string,sprintf('_rLP_LCA_%d_ms',exposure_time)];
            mkdir(new_foldername);
            cd([app.save_folder,'\',new_foldername]);
            
            % Ask if flat-field and dark images need to be taken
            prompt_string = 'Do you need to take calibration images (flat-field and dark frame images)?';
            button1 = 'Yes';
            button2 = 'No';
            calibration_ans = questdlg(prompt_string,'Calibration Images?',button1,button2,button1);
            
            num_images = 10;
            timeout = 10;
            degree_step = 30;
            n_steps = 180/degree_step;
            app.motor_det.SetAbsMovePos(0,app.det_RCA_LCA_settings(2,1));
            app.motor_det.MoveAbsolute(0,0);
            for img_set_num = 1:3
                if(img_set_num == 1) % 1 --> Sample image set
                    image_set = cell(1,1,n_steps);
                    for step = 1:n_steps
                        app.motor_ill.SetAbsMovePos(0,app.ill_degree_settings(1 + (step - 1)*(degree_step/5)));
                        app.motor_ill.MoveAbsolute(0,0);
                        moving = 1;
                        image_set{1,1,step} = zeros([app.FOV_pixels',num_images]);
                        current_time = clock;
                        while(moving == 1 && etime(clock,current_time) < timeout)
                            ill_status = app.motor_ill.GetStatusBits_Bits(0);
                            det_status = app.motor_det.GetStatusBits_Bits(0);
                            moving = are_K10CR1s_moving(ill_status,det_status);
                        end
                        if(step == 1)
                            pause(3);
                        end
                        pause(1);
                        for n = 1:num_images
                            image_set{1,1,step}(:,:,n) = snap_image(exposure_time,app.mmc);
                        end
                        pause(1);
                    end
                        app.motor_ill.MoveHome(0,0); % Move back to 0 before next acquisition
                        save('image_set.mat','image_set');
                        clear image_set
            
                        if(strcmp(calibration_ans,button1) == 1)
                            d = dialog('Position',[300 300 400 100],'Name','Next: Flat-field images');
                            d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                                'Calibration images needed. Click "Continue" once the sample has been removed for flat-field images.');
                            d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                                'Callback','delete(gcf)');
                            uiwait(d);
                        end
                elseif(image_set_num == 2) % 2 --> Flat-field images
                    if(strcmp(calibration_ans,button1) == 1)
                        ff_images = cell(1,1,n_steps);
                        for step = 1:n_steps
                            app.motor_ill.SetAbsMovePos(0,app.ill_degree_settings(1 + (step - 1)*(degree_step/5)));
                            app.motor_ill.MoveAbsolute(0,0);
                            moving = 1;
                            ff_images{1,1,step} = zeros([app.FOV_pixels',num_images]);
                            current_time = clock;
                            while(moving == 1 && etime(clock,current_time) < timeout)
                                ill_status = app.motor_ill.GetStatusBits_Bits(0);
                                det_status = app.motor_det.GetStatusBits_Bits(0);
                                moving = are_K10CR1s_moving(ill_status,det_status);
                            end
                            pause(1);
                            for n = 1:num_images
                                ff_images{1,1,step}(:,:,n) = snap_image(exposure_time,app.mmc);
                            end
                            pause(1);
                        end
            
                        app.motor_ill.MoveHome(0,0); % Move back to 0 before next acquisition
                        save('ff_images.mat','ff_images');
                        clear ff_images
            
                        d = dialog('Position',[300 300 400 100],'Name','Next: Dark frames');
                        d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                            'Calibration images needed. Click "Continue" once light has been blocked from reaching the camera for dark frames.');
                        d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                            'Callback','delete(gcf)');
                        uiwait(d);
                    end
                else % Dark frames
                    if(strcmp(calibration_ans,button1) == 1)
                        dark_frames = zeros([app.FOV_pixels',num_images]);
                        for n = 1:num_images
                            dark_frames(:,:,n) = snap_image(exposure_time,app.mmc);
                        end
                        save('dark_frames.mat','dark_frames');
                        clear dark_frames
                    end
                end
            end
            app.mmc.reset();
            guidata(hObject,handles);
        end

        % Button pushed function: rLP_rca_button
        function rLP_rca_button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to rLP_rca_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if(strcmp(get(app.camera_status,'String'),'Micro-Manager') == 1)
                d = dialog('Position',[300 300 400 100],'Name','Exit Micro-Manager');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    'Click "Continue" once Micro-Manager has been closed and the camera can be loaded into MATLAB.');
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                    'Callback','delete(gcf)');
                uiwait(d);
            end
            
            % Load camera through Micro-Manager core
            import mmcorej.*;
            app.mmc = CMMCore;
            app.mmc.loadSystemConfiguration ('C:\Program Files\Micro-Manager-2.0gamma\MMConfig_QCam_edit.cfg');
            
            set(app.camera_status,'String','MATLAB');
            
            % Request user input for exposure time
            title = 'Enter the exposure time for each image.';
            user_answer = inputdlg('Exposure time (ms):',title,[1 length(title)+30]);
            exposure_time = str2double(user_answer{1,1});
            cd(app.save_folder);
            title = 'Enter the name to save the image set under.';
            user_answer = inputdlg('Enter the save name for the sample image set (without any extensions):',...
                title,[1 length(title)+30]);
            save_string = user_answer{1,1};
            new_foldername = [save_string,sprintf('_rLP_RCA_%d_ms',exposure_time)];
            mkdir(new_foldername);
            cd([app.save_folder,'\',new_foldername]);
            
            % Ask if flat-field and dark images need to be taken
            prompt_string = 'Do you need to take calibration images (flat-field and dark frame images)?';
            button1 = 'Yes';
            button2 = 'No';
            calibration_ans = questdlg(prompt_string,'Calibration Images?',button1,button2,button1);
            
            num_images = 10;
            timeout = 10;
            degree_step = 30;
            n_steps = 180/degree_step;
            app.motor_det.SetAbsMovePos(0,app.det_RCA_LCA_settings(1,1));
            app.motor_det.MoveAbsolute(0,0);
            for img_set_num = 1:3
                if(img_set_num == 1) % 1 --> Sample image set
                    image_set = cell(1,1,n_steps);
                    for step = 1:n_steps
                        app.motor_ill.SetAbsMovePos(0,app.ill_degree_settings(1 + (step - 1)*(degree_step/5)));
                        app.motor_ill.MoveAbsolute(0,0);
                        moving = 1;
                        image_set{1,1,step} = zeros([app.FOV_pixels',num_images]);
                        current_time = clock;
                        while(moving == 1 && etime(clock,current_time) < timeout)
                            ill_status = app.motor_ill.GetStatusBits_Bits(0);
                            det_status = app.motor_det.GetStatusBits_Bits(0);
                            moving = are_K10CR1s_moving(ill_status,det_status);
                        end
                        if(step == 1)
                            pause(3); % Extra time in case it was not close to the zero setting
                        end
                        pause(1);
                        for n = 1:num_images
                            image_set{1,1,step}(:,:,n) = snap_image(exposure_time,app.mmc);
                        end
                        pause(1);
                    end
            
                    app.motor_ill.MoveHome(0,0); % Move back to 0 before next acquisition
                    save('image_set.mat','image_set');
                    clear image_set
            
                    if(strcmp(calibration_ans,button1) == 1)
                        d = dialog('Position',[300 300 400 100],'Name','Next: Flat-field images');
                        d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                            'Calibration images needed. Click "Continue" once the sample has been removed for flat-field images.');
                        d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                            'Callback','delete(gcf)');
                        uiwait(d);
                    end
            
                elseif(img_set_num == 2) % 2 --> Flat-field images
                    if(strcmp(calibration_ans,button1) == 1)
                        ff_images = cell(1,1,n_steps);
                        for step = 1:n_steps
                            app.motor_ill.SetAbsMovePos(0,app.ill_degree_settings(1 + (step - 1)*(degree_step/5)));
                            app.motor_ill.MoveAbsolute(0,0);
                            moving = 1;
                            ff_images{1,1,step} = zeros([app.FOV_pixels',num_images]);
                            current_time = clock;
                            while(moving == 1 && etime(clock,current_time) < timeout)
                                ill_status = app.motor_ill.GetStatusBits_Bits(0);
                                det_status = app.motor_det.GetStatusBits_Bits(0);
                                moving = are_K10CR1s_moving(ill_status,det_status);
                            end
                            pause(1);
                            for n = 1:num_images
                                ff_images{1,1,step}(:,:,n) = snap_image(exposure_time,app.mmc);
                            end
                            pause(1);
                        end
                        app.motor_ill.MoveHome(0,0); % Move back to 0 before next acquisition
                        save('ff_images.mat','ff_images');
                        clear ff_images
                        d = dialog('Position',[300 300 400 100],'Name','Next: Dark frames');
                        d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                            'Calibration images needed. Click "Continue" once light has been blocked from reaching the camera for dark frames.');
                        d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                            'Callback','delete(gcf)');
                        uiwait(d);
                    end
                else % Dark frames
                    if(strcmp(calibration_ans,button1) == 1)
                        dark_frames = zeros([app.FOV_pixels',num_images]);
                        for n = 1:num_images
                            dark_frames(:,:,n) = snap_image(exposure_time,app.mmc);
                        end
                        save('dark_frames.mat','dark_frames');
                        clear dark_frames
                    end
                end
            end
            app.mmc.reset();
            guidata(hObject,handles);
        end

        % Button pushed function: rcp_lca_button
        function rcp_lca_button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to rcp_lca_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.motor_ill.SetAbsMovePos(0,app.ill_RCA_LCA_settings(2,1));
            app.motor_det.SetAbsMovePos(0,app.det_RCA_LCA_settings(2,1));
            app.motor_ill.MoveAbsolute(0,0);
            app.motor_det.MoveAbsolute(0,0);
            set(app.ill_pol_display,'String',sprintf('%.4f degrees',app.ill_RCA_LCA_settings(2,1)));
            set(app.det_pol_display,'String',sprintf('%.4f degrees',app.det_RCA_LCA_settings(2,1)));
            moving = 1;
            timeout = 20;
            current_time = clock;
            while(moving == 1 && etime(clock,current_time) < timeout)
                ill_status = app.motor_ill.GetStatusBits_Bits(0);
                det_status = app.motor_det.GetStatusBits_Bits(0);
                moving = are_K10CR1s_moving(ill_status,det_status);
            end
            guidata(hObject,handles);
        end

        % Callback function
        function record_stitching_perimeter_button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to record_stitching_perimeter_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            fig_handle = figure('Name','Record Stitching Perimeter','Position',[300 300 400 100]);
            button_handle = uicontrol('Parent',fig_handle,'Position',[30 30 340 40],...
                'Style','PushButton','String', 'Done','Callback', 'delete(gcbf)');
            
            xy_coords = [0 0]; % [x y]
            disp('Coordinate recording starting...');
            [~,xy_coords(1,1)] = app.h_x.GetPosition(0,0);
            [~,xy_coords(1,2)] = app.h_y.GetPosition(0,0);
            fprintf('Return to starting position: [x, y] = [%.4f, %.4f]\n',xy_coords(1,1),xy_coords(1,2));
            done = 0;
            count = 1;
            while done == 0
                count = count + 1;
                [~,xy_coords(count,1)] = app.h_x.GetPosition(0,0);
                [~,xy_coords(count,2)] = app.h_y.GetPosition(0,0);
                drawnow;
                if ~ishandle(button_handle)
                    app.stitching_perimeter = xy_coords;
                    disp('Coordinate recording terminated.');
                    title = 'Enter the save name for this image set.';
                    user_answer = inputdlg('Enter the save name for this image set:',...
                        title,[1 length(title)+30]);
                    save_string = user_answer{1,1};
                    cd(app.save_folder);
                    mkdir(save_string);
                    app.perimeter_stitch_folder = [app.save_folder,'\',save_string];
                    cd(app.perimeter_stitch_folder);
                    save('stitching_perimeter_xy_coords.mat','xy_coords');
                    set(app.stitching_perimeter_display,'String','Set');
                    disp('Stitching perimeter XY coordinates saved.');
                    break;
                end
            end
            
            guidata(hObject, handles);
        end

        % Button pushed function: reset_camera_button
        function reset_camera_button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to reset_camera_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.mmc.reset();
            set(app.camera_status,'String','Micro-Manager');
            guidata(hObject, handles);
        end

        % Button pushed function: run_mp_stitch_button
        function run_mp_stitch_button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to run_mp_stitch_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Check if rectangular coordinates are entered into GUI
            if(strcmp(get(app.stitching_acquisition_display,'String'),'Ready') == 1)
                title = 'Enter the save name for this image set.';
                user_answer = inputdlg('Enter the save name for this image set:',...
                    title,[1 length(title)+30]);
                save_string = user_answer{1,1};
                cd(app.save_folder);
                mkdir(save_string);
                if(app.perimeter_stitching == 1)
                    app.perimeter_stitch_folder = [app.save_folder,'\',save_string];
                    cd(app.perimeter_stitch_folder);
                elseif(app.rectangular_stitching == 1)
                    app.rectangular_stitch_folder = [app.save_folder,'\',save_string];
                    cd(app.rectangular_stitch_folder);
                end
            else
                disp('There is not a grid currently loaded.');
                return;
            end
            
            [num_images_row,num_images_col] = size(app.x_img_mesh);
            
            % Ask user if they want to use one z-focus value or use sloped z-focus values from determine_z_focus_grid
            z_focus_ans = questdlg('Determine z_img_mesh now or load an existing z_img_mesh?',...
                'Select what grid to use for objective Z positioning.','Determine z_img_mesh now','Load z_img_mesh','Determine z_img_mesh now');
            
            if(strcmp(z_focus_ans,'Determine z_img_mesh now') == 1)
                z_extremes = zeros(2,4);
            
                % Ask user to determine upper/lower bounds of each corner for tiled zstacks
                for i = 1:4
                    if(i == 1)
                        app.h_y.SetAbsMovePos(0,app.y_img_mesh(1,num_images_col));app.h_y.MoveAbsolute(0,0);
                        app.h_x.SetAbsMovePos(0,app.x_img_mesh(1,num_images_col));app.h_x.MoveAbsolute(0,0);
                        disp_text = 'Upper Left:';
            
                        % Ask user to switch to correct objective and view with Micro-Manager
                        d = dialog('Position',[300 300 400 100],'Name','Use Micro-Manager for Live View');
                        d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                            ['Move ',app.obj_string,' into place and view sample with Micro-Manager.']);
                        d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                            'Callback','delete(gcf)');
                        uiwait(d);
                        set(app.camera_status,'String','Micro-Manager');
            
                    elseif(i == 2)
                        app.h_y.SetAbsMovePos(0,app.y_img_mesh(1,1));app.h_y.MoveAbsolute(0,0);
                        app.h_x.SetAbsMovePos(0,app.x_img_mesh(1,1));app.h_x.MoveAbsolute(0,0);
                        disp_text = 'Upper Right:';
                    elseif(i == 3)
                        app.h_y.SetAbsMovePos(0,app.y_img_mesh(num_images_row,num_images_col));app.h_y.MoveAbsolute(0,0);
                        app.h_x.SetAbsMovePos(0,app.x_img_mesh(num_images_row,num_images_col));app.h_x.MoveAbsolute(0,0);
                        disp_text = 'Lower Left:';
                    elseif(i == 4)
                        app.h_y.SetAbsMovePos(0,app.y_img_mesh(num_images_row,1));app.h_y.MoveAbsolute(0,0);
                        app.h_x.SetAbsMovePos(0,app.x_img_mesh(num_images_row,1));app.h_x.MoveAbsolute(0,0);
                        disp_text = 'Lower Right:';
                    end
            
                    for n = 1:2
                        % Automatically launch Micro-Manager
                        if(i == 1 && n == 1)
                            disp('Open Micro-Manager');
                        else
                            !C:\Program Files\Micro-Manager-2.0gamma\ImageJ.exe
                            set(app.camera_status,'String','Micro-Manager');
                        end
                        if(n == 1)
                            prompt_string = 'Choose one outer focal plane and click "Continue" once Micro-Manager has been closed.';
                        elseif(n == 2)
                            prompt_string = 'Choose the other outer focal plane and click "Continue" once Micro-Manager has been closed.';
                        end
                        % Ask user to bring sample into focus and to close the Micro-Manager interface
                        ask_user_mmc(app, prompt_string);
                        pause(0.5);
            
                        % Load microscope hardware through Micro-Manager core and get objective position
                        import mmcorej.*;
                        app.mmc = CMMCore;
                        app.mmc.loadSystemConfiguration ('C:\Program Files\Micro-Manager-2.0gamma\MMConfig_full_microscope_control.cfg'); % Config file with z-stage control
                        set(app.camera_status,'String','MATLAB');
            
                        z_extremes(n,i) = app.mmc.getPosition;
            
                        app.mmc.reset();
                    end
            
                end
                z_extremes = sort(z_extremes,1);
            
                z_depth = ceil(max(z_extremes(2,:) - z_extremes(1,:)));
                z_img_mesh = zeros(num_images_row,num_images_col,z_depth);
            
                % Calculate z_img_mesh values across the entire set of tiles at all z_depth coordinates
                for z = 1:z_depth
                    % Indexing is weird because of L/R flip of stage coordinate system
                    z_img_mesh(1,:,z) = linspace(z_extremes(1,2) + (z - 1),z_extremes(1,1) + (z - 1),num_images_col);
                    z_img_mesh(num_images_row,:,z) = linspace(z_extremes(1,4) + (z - 1),z_extremes(1,3) + (z - 1),num_images_col);
            
                    for n = 1:size(z_img_mesh,2)
                        z_img_mesh(1:num_images_row,n,z) = linspace(z_img_mesh(1,n,z),z_img_mesh(num_images_row,n,z),num_images_row)';
                    end
                end
                save('image_z_coords.mat','z_img_mesh','-v7.3');
            else
                [filename,folder] = uigetfile(app.save_folder);
                temp_struct = load([folder,'\',filename],'z_img_mesh');
                z_img_mesh = temp_struct.('z_img_mesh');
                z_depth = size(z_img_mesh,3);
            end
            
            app.z_img_mesh = z_img_mesh;
            
            % Good point to break into separate functions
            % z_img_mesh = app.z_img_mesh;
            
            
            % Ask for two exposure times (low and high)
            exp_time_defaults = {'100','500'};
            exp_time = inputdlg({'Exposure time 1 (ms):','Exposure time 2 (ms):'},'Enter exposure times.',[1,60],exp_time_defaults);
            if(isempty(exp_time) == 1)
                return
            else
                exp_times(1,1) = str2double(exp_time{1,1});
                exp_times(2,1) = str2double(exp_time{2,1});
                clear exp_time
            end
            
            % Make folders for the different exposure times
            mkdir(sprintf('exp%dms',exp_times(1,1)));
            mkdir(sprintf('exp%dms',exp_times(2,1)));
            
            % Load camera through Micro-Manager core
            import mmcorej.*;
            app.mmc = CMMCore;
            app.mmc.loadSystemConfiguration ('C:\Program Files\Micro-Manager-2.0gamma\MMConfig_full_microscope_control.cfg'); % Config file with z-stage control
            
            set(app.camera_status,'String','MATLAB');
            
            % Stitching Acquisition (for ImageJ Stitching plugin):
            % Grid: row-by-row
            % Order: snake (left-to-right row 1)
            disp('Beginning stitching acquisition');
            
            y = 0;
            img_index = 0;
            for y_loop = 1:size(app.x_img_mesh,1)
                y = y + 1;
            
                for x_loop = 1:size(app.x_img_mesh,2)
                    if(mod(y,2) == 1)
                        % For odd number rows, moving left to right
                        x = x_loop;
                    else
                        % For even number rows, moving right to left
                        x = size(app.x_img_mesh,2) - x_loop + 1;
                    end
                    y_current = app.y_img_mesh(y,x);
                    x_current = app.x_img_mesh(y,x);
                    app.h_y.SetAbsMovePos(0,y_current);app.h_y.MoveAbsolute(0,0);
                    app.h_x.SetAbsMovePos(0,x_current);app.h_x.MoveAbsolute(0,0);
                    pause(0.5);
            
                    % Move objective across z_img_mesh values for the current tile
                    img = cell(2,1);
                    img{1,1} = zeros(app.FOV_pixels(1,1),app.FOV_pixels(2,1),z_depth);
                    img{2,1} = zeros(app.FOV_pixels(1,1),app.FOV_pixels(2,1),z_depth);
                    for z = 1:size(app.z_img_mesh,3)
                        app.mmc.setPosition(z_img_mesh(y,x,z));
                        pause(0.5);
                        %  for i = 2:length(exp_times) % Skipping lower exposure time to speed up imaging for cerebral arteries Feb 9, 2021
                        for i = 1:length(exp_times)
                            img{i,1}(:,:,z) = snap_image(exp_times(i,1),app.mmc);
                        end
                    end
            
                    img_index = img_index + 1;
            
                    % Save multi-exposure images for current xy location
            %         for i = 2:length(exp_times) % Skipping lower exposure time to speed up imaging for cerebral arteries Feb 9, 2021
                    for i = 1:length(exp_times)
                        if(app.rectangular_stitching == 1)
                            cd([app.rectangular_stitch_folder,'\',sprintf('exp%dms',exp_times(i,1))]);
                        elseif(app.perimeter_stitching == 1)
                            cd([app.perimeter_stitch_folder,'\',sprintf('exp%dms',exp_times(i,1))]);
                        end
            
                        if(img_index < 10)
                            new_filename = sprintf('img_00%d',img_index);
                        elseif(img_index > 99)
                            new_filename = sprintf('img_%d',img_index);
                        else
                            new_filename = sprintf('img_0%d',img_index);
                        end
            
                        % Save .mat file and .tiff zstack
                        save([new_filename,'.mat'],'img');
            
                        for z = 1:z_depth
                            imwrite(uint16(img{i,1}(:,:,z)),[new_filename,'.tif'],'tif','WriteMode','append');
                        end
                    end
                end
            end
            disp('Done with stitching acquisition. Saving...');
            if(app.rectangular_stitching == 1)
                cd(app.rectangular_stitch_folder);
            elseif(app.perimeter_stitching == 1)
                cd(app.perimeter_stitch_folder);
            end
            disp('Done saving');
            
            app.mmc.reset();
            guidata(hObject, handles);
        end

        % Button pushed function: run_sp_af_stitch_button
        function run_sp_af_stitch_button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to run_sp_af_stitch_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Ask user to close Micro-Manager interface
            if(strcmp(get(app.camera_status,'String'),'Micro-Manager') == 1)
                d = dialog('Position',[300 300 400 100],'Name','Exit Micro-Manager');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    'Click "Continue" once Micro-Manager has been closed and the camera can be loaded into MATLAB.');
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                    'Callback','delete(gcf)');
                uiwait(d);
            end
            
            % Ask for two exposure times (low and high)
            exp_time_defaults = {'40','400'};
            exp_time = inputdlg({'Exposure time 1 (ms):','Exposure time 2 (ms):'},'Enter exposure times.',[1,60],exp_time_defaults);
            if(isempty(exp_time) == 1)
                return
            else
                exp_times(1,1) = str2double(exp_time{1,1});
                exp_times(2,1) = str2double(exp_time{2,1});
                clear exp_time
            end
            
            title = 'Enter the save name for this image set.';
            user_answer = inputdlg('Enter the save name for this image set:',...
                title,[1 length(title)+30]);
            save_string = user_answer{1,1};
            cd(app.save_folder);
            mkdir(save_string);
            app.perimeter_stitch_folder = [app.save_folder,'\',save_string];
            cd(app.perimeter_stitch_folder);
            
            % Load camera through Micro-Manager core
            import mmcorej.*;
            app.mmc = CMMCore;
            app.mmc.loadSystemConfiguration ('C:\Program Files\Micro-Manager-2.0gamma\MMConfig_full_microscope_control.cfg'); % Config file with z-stage control
            
            set(app.camera_status,'String','MATLAB');
            app.mmc.setConfig('Objective',app.obj_string);
            % app.mmc.setConfig('LightPath','CameraPort');
            
            if(app.rectangular_stitching == 1)
                % Rectangular --> auto-focus for the 4 corner positions
                cd(app.rectangular_stitch_folder);
                disp('Auto-focusing for the 4 corners of the rectangular ROI');
                z_focus = zeros(size(app.x_img_mesh));
            
                for i = 1:4
                    if(i == 1)
                        app.h_y.SetAbsMovePos(0,app.ULy);app.h_y.MoveAbsolute(0,0);
                        app.h_x.SetAbsMovePos(0,app.ULx);app.h_x.MoveAbsolute(0,0);
                        corner_indices = [1, 1];
                        disp_text = 'Upper Left:';
                    elseif(i == 2)
                        app.h_y.SetAbsMovePos(0,app.ULy);app.h_y.MoveAbsolute(0,0);
                        app.h_x.SetAbsMovePos(0,app.LRx);app.h_x.MoveAbsolute(0,0);
                        corner_indices = [1, size(app.x_img_mesh,2)];
                        disp_text = 'Upper Right:';
                    elseif(i == 3)
                        app.h_y.SetAbsMovePos(0,app.LRy);app.h_y.MoveAbsolute(0,0);
                        app.h_x.SetAbsMovePos(0,app.ULx);app.h_x.MoveAbsolute(0,0);
                        corner_indices = [size(app.x_img_mesh,1), 1];
                        disp_text = 'Lower Left:';
                    elseif(i == 4)
                        app.h_y.SetAbsMovePos(0,app.LRy);app.h_y.MoveAbsolute(0,0);
                        app.h_x.SetAbsMovePos(0,app.LRx);app.h_x.MoveAbsolute(0,0);
                        corner_indices = [size(app.x_img_mesh,1), size(app.x_img_mesh,2)];
                        disp_text = 'Lower Right:';
                    end
            
            
                    % Determine the most in-focus z-plane at each corner of the ROI
                    done = 0;
                    fine_adj = 0;
                    n = 0;
            
                    fprintf(['\n',disp_text,' Finding best focus...']);
                    while(done == 0)
                        n = n + 1;
                        if(n == 1 && fine_adj == 0)
                            fprintf('\n(Coarse) Iteration # %d',n);
                            increment = 50;
                        elseif(n == 1 && fine_adj == 1)
                            fprintf('\n(Fine) Iteration # %d',n);
                            increment = 5;
                        else
                            fprintf(', %d',n);
                        end
            
                        if(n == 1)
                            current_z = app.mmc.getPosition;
                            pause(1);
            
                        elseif(n == 2)
                            direction = 1; % positive
                            current_z = current_z + direction*increment; % Move objective block up
                        else
                            % If most recent iteration was more in-focus than the second most recent iteration, continue in that direction
                            if(fm(n - 1,1) > fm(n - 2,1))
                                % Don't change direction
                            else
                                direction = -direction;
                            end
                            current_z = current_z + direction*increment;
                        end
            
            
                        app.mmc.setPosition(current_z);
                        pause(1);
                        temp_img = double(snap_image(exp_times(1,1),app.mmc));
                        fm(n,1) = fmeasure(temp_img,'BREN');
            
                        % After n = 3 iteration, the direction the z-stage needs to move has been determined
                        if(n > 3)
                            if(fm(n,1) < fm(n-1,1))
                                current_z = current_z - direction*increment;
                                if(fine_adj == 0)
                                    fine_adj = 1;
                                    n = 0;
                                else
                                    done = 1;
                                end
                            end
                        end
            
                        z_focus(corner_indices) = current_z;
                    end
                end
                save('z_focus_corners.mat','z_focus');
                disp('Done with corner auto-focusing.');
            elseif(app.perimeter_stitching == 1)
                % Perimeter --> auto-focus for the 8 corner positions
                cd(app.perimeter_stitch_folder);
                disp('Auto-focusing for 8 corners of the perimeter contour.');
            
                % Find z-focus for xy edges
                z_focus = zeros(size(app.x_img_mesh));
                z_focus(app.empty_tile == false) = 1;
            
                current_z = app.mmc.getPosition;
                current_z = 10*round(current_z/10); % Round off z-positions to nearest 10
                app.mmc.setPosition(current_z);
            
                % Go to each corner location and determine best focus
                x_ind_save = 0;
                y_ind_save = 0;
                for i = 1:4
                    for j = 1:2
                        if(i == 1) % Left-most sample edge (maximum x values)
                            disp_text = 'Left edge';
                            [~,x_ind] = max(app.x_img_mesh(1,:));
            
                            if(app.mag_index ~= 1) % For magnifications greater than 4X
                                x_ind = x_ind - 1; % Move towards center for auto-focusing
                            end
                            edge = find(~app.empty_tile(:,x_ind));
                            if(j == 1) % Top of left edge
                                disp_text = [disp_text,' (top):'];
                                [y_ind,~] = min(edge);
                                if(app.mag_index ~= 1) % For magnifications greater than 4X
                                    y_ind = y_ind + 1; % Move towards center for auto-focusing
                                end
                            elseif(j == 2) % Bottom of left edge
                                disp_text = [disp_text,' (bottom):'];
                                [y_ind,~] = max(edge);
                                if(app.mag_index ~= 1) % For magnifications greater than 4X
                                    y_ind = y_ind - 1; % Move towards center for auto-focusing
                                end
                            end
                        elseif(i == 2) % Right-most sample edge (minimum x values)
                            disp_text = 'Right edge';
                            [~,x_ind] = min(app.x_img_mesh(1,:));
            
                            if(app.mag_index ~= 1) % For magnifications greater than 4X
                                x_ind = x_ind + 1; % Move towards center for auto-focusing
                            end
                            edge = find(~app.empty_tile(:,x_ind));
                            if(j == 1) % Top of right edge
                                disp_text = [disp_text,' (top):'];
                                [y_ind,~] = min(edge);
                                if(app.mag_index ~= 1) % For magnifications greater than 4X
                                    y_ind = y_ind + 1; % Move towards center for auto-focusing
                                end
                            elseif(j == 2) % Bottom of right edge
                                disp_text = [disp_text,' (bottom):'];
                                [y_ind,~] = max(edge);
                                if(app.mag_index ~= 1) % For magnifications greater than 4X
                                    y_ind = y_ind - 1; % Move towards center for auto-focusing
                                end
                            end
                        elseif(i == 3) % Top-most sample edge (minimum y values)
                            disp_text = 'Top edge';
                            [~,y_ind] = min(app.y_img_mesh(:,1));
            
                            if(app.mag_index ~= 1) % For magnifications greater than 4X
                                y_ind = y_ind + 1; % Move towards center for auto-focusing
                            end
                            edge = find(~app.empty_tile(y_ind,:));
                            if(j == 1) % Left of top edge
                                disp_text = [disp_text,' (left):'];
                                [x_ind,~] = max(edge);
                                if(app.mag_index ~= 1) % For magnifications greater than 4X
                                    x_ind = x_ind - 1; % Move towards center for auto-focusing
                                end
                            elseif(j == 2) % Right of top edge
                                disp_text = [disp_text,' (right):'];
                                [x_ind,~] = min(edge);
                                if(app.mag_index ~= 1) % For magnifications greater than 4X
                                    x_ind = x_ind + 1; % Move towards center for auto-focusing
                                end
                                x_ind_save = x_ind;
                                y_ind_save = y_ind;
                            end
                        elseif(i == 4) % Bottom-most sample edge (maximum y values)
                            disp_text = 'Bottom edge';
                            [~,y_ind] = max(app.y_img_mesh(:,1));
            
                            if(app.mag_index ~= 1) % For magnifications greater than 4X
                                y_ind = y_ind - 1; % Move towards center for auto-focusing
                            end
                            edge = find(~app.empty_tile(y_ind,:));
                            if(j == 1) % Left of bottom edge
                                disp_text = [disp_text,' (left):'];
                                [x_ind,~] = max(edge);
                                if(app.mag_index ~= 1) % For magnifications greater than 4X
                                    x_ind = x_ind - 1; % Move towards center for auto-focusing
                                end
                            elseif(j == 2) % Right of bottom edge
                                disp_text = [disp_text,' (right):'];
                                [x_ind,~] = min(edge);
                                if(app.mag_index ~= 1) % For magnifications greater than 4X
                                    x_ind = x_ind + 1; % Move towards center for auto-focusing
                                end
                            end
                        end
            
                        app.h_y.SetAbsMovePos(0,app.y_img_mesh(y_ind,x_ind));app.h_y.MoveAbsolute(0,0);
                        app.h_x.SetAbsMovePos(0,app.x_img_mesh(y_ind,x_ind));app.h_x.MoveAbsolute(0,0);
            
                        % Give option to restart auto-focusing from new start location
                        corner_done = 0;
                        while(corner_done == 0)
            
                            % Auto-focus
                            done = 0;
                            fine_adj = 0;
                            n = 0;
                            exp_time_autofocus = exp_times(2,1);
            
                            fprintf(['\n',disp_text,' Finding best focus...']);
                            while(done == 0)
                                n = n + 1;
                                if(n == 1 && fine_adj == 0)
                                    fprintf('\n(Coarse) Iteration # %d',n);
                                    increment = 50;
                                elseif(n == 1 && fine_adj == 1)
                                    fprintf('\n(Fine) Iteration # %d',n);
                                    increment = 5;
                                else
                                    fprintf(', %d',n);
                                end
            
                                if(n == 1)
                                    current_z = app.mmc.getPosition;
                                    pause(1);
            
                                elseif(n == 2)
                                    direction = 1; % positive
                                    current_z = current_z + direction*increment; % Move objective block up
                                else
                                    % If most recent iteration was more in-focus than the second most recent iteration, continue in that direction
                                    if(fm(n - 1,1) > fm(n - 2,1))
                                        % Don't change direction
                                    else
                                        direction = -direction;
                                    end
                                    current_z = current_z + direction*increment;
                                end
            
                                app.mmc.setPosition(current_z);
                                pause(1);
            
                                if(n == 1)
                                    saturated = 1;
                                    while(saturated == 1)
                                        temp_img = double(snap_image(exp_time_autofocus,app.mmc));
            
                                        % If saturated, lower the exposure time
                                        if(max(max(temp_img)) > 65500)
                                            exp_time_autofocus = exp_time_autofocus/2;
                                        else
                                            saturated = 0;
                                        end
                                    end
                                else
                                    temp_img = double(snap_image(exp_time_autofocus,app.mmc));
                                end
                                fm(n,1) = fmeasure(temp_img,'BREN');
            
                                % After n = 3 iteration, the direction the z-stage needs to move has been determined
                                if(n > 3)
                                    if(fm(n,1) < fm(n-1,1))
                                        current_z = current_z - direction*increment;
                                        if(fine_adj == 0)
                                            fine_adj = 1;
                                            n = 0;
                                        else
                                            done = 1;
                                        end
                                    end
                                end
                            end
            
                            % Ask user if the focus looks good
                            temp_fig = figure;
                            imshow(uint16(temp_img));
            
                            auto_focus_ans = questdlg(sprintf('Z = %.2f microns. Does this look in focus?',current_z),'In focus?','Yes','No - Re-focus','Yes');
                            if(strcmp(auto_focus_ans,'Yes') == 1)
                                z_focus(y_ind,x_ind) = current_z;
                                corner_done = 1;
                            elseif(strcmp(auto_focus_ans,'No - Re-focus') == 1)
                                title = 'Enter new Z-plane.';
                                user_answer = inputdlg(sprintf('Previous Z = %.2f microns. Restart auto-focusing at (microns):',current_z),...
                                    title,[1 length(title)+50]);
                                current_z = str2double(user_answer{1,1});
                                app.mmc.setPosition(current_z);
                                pause(2);
                                corner_done = 0;
                            end
                            close(temp_fig);
            
                        end
                    end
                end
            
                save('z_focus_corners.mat','z_focus');
                disp('Done with corner auto-focusing.');
            end
            
            % Make folders for the different exposure times
            mkdir(sprintf('exp%dms',exp_times(1,1)));
            mkdir(sprintf('exp%dms',exp_times(2,1)));
            
            % Stitching Acquisition (for ImageJ Stitching plugin):
            % Grid: row-by-row
            % Order: snake (left-to-right row 1)
            disp('Beginning stitching acquisition');
            
            y = 0;
            img_index = 0;
            img = cell(size(app.x_img_mesh));
            z_focus_last = z_focus(y_ind_save,x_ind_save);
            for y_loop = 1:size(app.x_img_mesh,1)
                y = y + 1;
            
                for x_loop = 1:size(app.x_img_mesh,2)
                    if(mod(y,2) == 1)
                        % For odd number rows, moving left to right
                        x = x_loop;
                    else
                        % For even number rows, moving right to left
                        x = size(app.x_img_mesh,2) - x_loop + 1;
                    end
                    y_current = app.y_img_mesh(y,x);
                    x_current = app.x_img_mesh(y,x);
                    app.h_y.SetAbsMovePos(0,y_current);app.h_y.MoveAbsolute(0,0);
                    app.h_x.SetAbsMovePos(0,x_current);app.h_x.MoveAbsolute(0,0);
                    pause(0.5);
            
                    if(z_focus(y,x) == 0)
                        % Skip empty tiles (z_focus == 0 for empty tiles)
                        for i = 1:length(exp_times)
                            img{y,x}(:,:,i) = uint16(zeros(app.FOV_pixels'));
                        end
                    elseif(z_focus(y,x) == 1)
            
                        % Auto-focus
                        done = 0;
                        fine_adj = 0;
                        n = 0;
                        disp_text = sprintf('[y, x] = [%d, %d]:',y,x);
                        fprintf(['\n',disp_text,' Finding best focus...']);
                        while(done == 0)
                            n = n + 1;
                            if(n == 1 && fine_adj == 0)
                                    fprintf('\n(Coarse) Iteration # %d',n);
                                    increment = 50;
                                elseif(n == 1 && fine_adj == 1)
                                    fprintf('\n(Fine) Iteration # %d',n);
                                    increment = 5;
                                else
                                    fprintf(', %d',n);
                                end
            
                            if(n == 1)
                                current_z = z_focus_last;
            
                            elseif(n == 2)
                                direction = 1; % positive
                                current_z = current_z + direction*increment; % Move objective block up
                            else
                                % If most recent iteration was more in-focus than the second most recent iteration, continue in that direction
                                if(fm(n - 1,1) > fm(n - 2,1))
                                    % Don't change direction
                                else
                                    direction = -direction;
                                end
                                current_z = current_z + direction*increment;
                            end
            
                            app.mmc.setPosition(current_z);
                            pause(1);
            
                            if(n == 1)
                                temp_img = double(snap_image(exp_times(1,1),app.mmc));
                            else
                                temp_img = double(snap_image(exp_times(1,1),app.mmc));
                            end
                            fm(n,1) = fmeasure(temp_img,'BREN');
            
                            % After n = 3 iteration, the direction the z-stage needs to move has been determined
                            if(n > 3)
                                if(fm(n,1) < fm(n-1,1))
                                    current_z = current_z - direction*increment;
                                    if(fine_adj == 0)
                                        fine_adj = 1;
                                        n = 0;
                                    else
                                        done = 1;
                                    end
                                end
                            end
                        end
                        z_focus(y,x) = current_z;
                        z_focus_last = z_focus(y,x);
                        for i = 1:length(exp_times)
                            img{y,x}(:,:,i) = snap_image(exp_times(i,1),app.mmc);
                        end
                    else
                        % Pre-determined focal planes from corner auto-focusing
                        app.mmc.setPosition(z_focus(y,x));
                        pause(1);
                        for i = 1:length(exp_times)
                            img{y,x}(:,:,i) = snap_image(exp_times(i,1),app.mmc);
                        end
                        z_focus_last = z_focus(y,x);
                    end
            
                    img_index = img_index + 1;
            
                    % Save multi-exposure images for current xy location
                    for i = 1:length(exp_times)
                        if(app.rectangular_stitching == 1)
                            cd([app.rectangular_stitch_folder,'\',sprintf('exp%dms',exp_times(i,1))]);
                        elseif(app.perimeter_stitching == 1)
                            cd([app.perimeter_stitch_folder,'\',sprintf('exp%dms',exp_times(i,1))]);
                        end
            
                        if(img_index < 10)
                            new_filename = sprintf('img_00%d.tif',img_index);
                        elseif(img_index > 99)
                            new_filename = sprintf('img_%d.tif',img_index);
                        else
                            new_filename = sprintf('img_0%d.tif',img_index);
                        end
                        % Save images with indexing for ImageJ Stitching plug-in
                        imwrite(uint16(img{y,x}(:,:,i)),new_filename);
            
                        % Update saved Z-plane information
                        save('z_focus.mat','z_focus');
                    end
                end
            end
            fprintf('\n');
            disp('Done with stitching acquisition. Saving...');
            if(app.rectangular_stitching == 1)
                cd(app.rectangular_stitch_folder);
            elseif(app.perimeter_stitching == 1)
                cd(app.perimeter_stitch_folder);
            end
            save('img.mat','img','-v7.3');
            disp('Done saving');
            app.mmc.reset();
            guidata(hObject, handles);
        end

        % Button pushed function: run_sp_qBRM_stitch_button
        function run_sp_qBRM_stitch_button_Callback(app, event)

            % Ask user to bring sample into focus, to remove illumination QWP, and to close the Micro-Manager interface
            ask_user_mmc(app, 'Bring sample into focus and close Micro-Manager');
            
            % Ask for one exposure time
            exp_time_default = {'50'};
            exp_time = inputdlg({'Exposure time (ms):'},'Enter exposure time.',[1,60],exp_time_default);
            if(isempty(exp_time) == 1)
                return
            else
                exp_time = str2double(exp_time{1,1});
            end
            
            title = 'Enter the save name for this image set.';
            user_answer = inputdlg('Enter the save name for this image set:',...
                title,[1 length(title)+30]);
            save_string = user_answer{1,1};
            cd(app.save_folder);
            mkdir(save_string);
            if(app.perimeter_stitching == 1)
                app.perimeter_stitch_folder = [app.save_folder,'\',save_string];
                cd(app.perimeter_stitch_folder);
            elseif(app.rectangular_stitching == 1)
                app.rectangular_stitch_folder = [app.save_folder,'\',save_string];
                cd(app.rectangular_stitch_folder);
            end
            
            % Load camera through Micro-Manager core
            import mmcorej.*;
            app.mmc = CMMCore;
            app.mmc.loadSystemConfiguration ('C:\Program Files\Micro-Manager-2.0gamma\MMConfig_full_microscope_control.cfg'); % Config file with z-stage control
            
            set(app.camera_status,'String','MATLAB');
            app.mmc.setConfig('Objective',app.obj_string);
            % app.mmc.setConfig('LightPath','CameraPort');
            
            % Run single-plane stitching acquisition at z_focus_value
            % Using current objective position for z_focus
            
            % Ask user if they want to use current focus or load a matrix of values
            % load_focus = 0;
            load_focus = 1;
            if(load_focus == 0)
                z_focus_value = app.mmc.getPosition;
            
                z_focus = zeros(size(app.x_img_mesh));
                z_focus(app.empty_tile == false) = 1;
            else
                [filename,folder] = uigetfile(app.save_folder);
                temp_struct = load([folder,'\',filename],'z_focus');
                z_focus = temp_struct.('z_focus');
            end
            
            % Make folders for the different illumination polarizer angles
            num_qBRM_images = 6; % Using 6 evenly spaced angles for qBRM: 0, 30, 60, 90, 120, 150
            step_val = 180/(num_qBRM_images*5); % 5 degree spacing between saved motorized stage position values --> 6 steps for 30 degrees
            
            for q = 1:num_qBRM_images
                mkdir(sprintf('qBRM_img%d',q));
            end
            
            % Stitching Acquisition (for ImageJ Stitching plugin):
            % Grid: row-by-row
            % Order: snake (left-to-right row 1)
            disp('Beginning stitching acquisition');
            n_burst = 10;
            qBRM_img = cell([size(app.x_img_mesh),length(num_qBRM_images)]); % Changing img to accomodate 10-image bursts for ff correction
            for q = 1:num_qBRM_images
                % Save qBRM images for current xy location
                if(app.rectangular_stitching == 1)
                    cd([app.rectangular_stitch_folder,'\',sprintf('qBRM_img%d',q)]);
                elseif(app.perimeter_stitching == 1)
                    cd([app.perimeter_stitch_folder,'\',sprintf('qBRM_img%d',q)]);
                end
                app.motor_ill.SetAbsMovePos(0,app.ill_degree_settings((q - 1)*step_val + 1));
                app.motor_ill.MoveAbsolute(0,0);
                moving = 1;
                timeout = 20;
                current_time = clock;
                while(moving == 1 && etime(clock,current_time) < timeout)
                    ill_status = app.motor_ill.GetStatusBits_Bits(0);
                    det_status = app.motor_det.GetStatusBits_Bits(0);
                    moving = are_K10CR1s_moving(ill_status,det_status);
                end
                if(q == 1)
                    pause(3); % Extra pause to allow the motorized stage to get to the first setting
                end
                pause(1);
                y = 0;
                img_index = 0;
                for y_loop = 1:size(app.x_img_mesh,1)
                    y = y + 1;
            
                    for x_loop = 1:size(app.x_img_mesh,2)
                        if(mod(y,2) == 1)
                            % For odd number rows, moving left to right
                            x = x_loop;
                        else
                            % For even number rows, moving right to left
                            x = size(app.x_img_mesh,2) - x_loop + 1;
                        end
                        y_current = app.y_img_mesh(y,x);
                        x_current = app.x_img_mesh(y,x);
                        app.h_y.SetAbsMovePos(0,y_current);app.h_y.MoveAbsolute(0,0);
                        app.h_x.SetAbsMovePos(0,x_current);app.h_x.MoveAbsolute(0,0);
                        pause(0.2);
            
                        if(z_focus(y,x) == 0)
                            % Skip empty tiles (z_focus == 0 for empty tiles)
                            qBRM_img{y,x,q}(:,:,1) = uint16(zeros(app.FOV_pixels'));
                        else
                            % Move objective z position
                            app.mmc.setPosition(z_focus(y,x));
                            pause(0.5);
            
                            for n = 1:n_burst % 10-image burst for ff correction
                                qBRM_img{y,x,q}(:,:,n) = snap_image(exp_time,app.mmc);
                            end
                            img = qBRM_img{y,x,q};
                        end
            
                        img_index = img_index + 1;
                        if(img_index < 10)
                            new_filename = sprintf('img_00%d',img_index);
                        elseif(img_index > 99)
                            new_filename = sprintf('img_%d',img_index);
                        else
                            new_filename = sprintf('img_0%d',img_index);
                        end
                        % Save images with indexing for ImageJ Stitching plug-in
                        imwrite(uint16(qBRM_img{y,x,q}(:,:,1)),[new_filename,'.tif']); % Saving just the first image of the 10-image burst as tif
                        save([new_filename,'.mat'],'img','-v7.3');
                    end
            
                end
            end
            disp('Done with stitching acquisition. Saving...');
            if(app.rectangular_stitching == 1)
                cd(app.rectangular_stitch_folder);
            elseif(app.perimeter_stitching == 1)
                cd(app.perimeter_stitch_folder);
            end
            save('z_focus.mat','z_focus');
            % save('qBRM_img.mat','qBRM_img','-v7.3');
            disp('Done saving');
            
            % Acquire flat-field and dark frame images for flat-field correction
            
            % Ask user to go to an area of the slide that does not contain the sample (for flat-field correction)
            d = dialog('Position',[300 300 400 100],'Name','Flat-field Images Required');
            d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                'Click "Continue" once the stage has been moved to an area that does not contain the sample.');
            d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                'Callback','delete(gcf)');
            uiwait(d);
            ff_img = cell(num_qBRM_images,1);
            df_img = zeros(app.FOV_pixels(1,1),app.FOV_pixels(2,1),n_burst);
            for q = 1:num_qBRM_images
                app.motor_ill.SetAbsMovePos(0,app.ill_degree_settings((q - 1)*step_val + 1));
                app.motor_ill.MoveAbsolute(0,0);
                moving = 1;
                current_time = clock;
                while(moving == 1 && etime(clock,current_time) < timeout)
                    ill_status = app.motor_ill.GetStatusBits_Bits(0);
                    det_status = app.motor_det.GetStatusBits_Bits(0);
                    moving = are_K10CR1s_moving(ill_status,det_status);
                end
                if(q == 1)
                    pause(3); % Extra pause to allow the motorized stage to get to the first setting
                end
                pause(1);
                for n = 1:n_burst % 10-image burst for ff correction
                    ff_img{q,1}(:,:,n) = snap_image(exp_time,app.mmc);
                end
            end
            
            % Disable computer control of microscope to allow user to change light path to eyepieces for dark frames
            app.mmc.reset();
            import mmcorej.*;
            app.mmc = CMMCore;
            app.mmc.loadSystemConfiguration ('C:\Program Files\Micro-Manager-2.0gamma\MMConfig_QCam_edit.cfg'); % Config file with z-stage control
            % Ask user to block light from reaching the camera (for flat-field correction)
            d = dialog('Position',[300 300 400 100],'Name','Dark Frame Images Required');
            d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                'Click "Continue" once the light path has been changed to the eyepieces (to block light from reaching camera).');
            d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                'Callback','delete(gcf)');
            uiwait(d);
            for n = 1:n_burst % 10-image burst for ff correction
                df_img(:,:,n) = snap_image(exp_time,app.mmc);
            end
            save('ff_corr_qBRM_img.mat','ff_img','df_img','-v7.3');
            disp('Done saving');
            
            app.mmc.reset();
            guidata(hObject, handles);
        end

        % Button pushed function: run_sp_stitch_button
        function run_sp_stitch_button_Callback(app, event)
            input_save_filename(app);
            
            % Ask user if they want to use one z-focus value or use sloped z-focus values from determine_z_focus_grid
            z_focus_ans = questdlg('Use one z-focus value (current objective position) or z-focus grid values?',...
                'Select method of objective positioning','Use single objective position','Use Z-focus grid values','Use current objective position');
            if(strcmp(z_focus_ans,'Use single objective position') == 1)
                !C:\Program Files\Micro-Manager-2.0gamma\ImageJ.exe
                app.camera_status.Text = 'Micro-Manager';
            
                % Ask user to bring sample into focus and to close the Micro-Manager interface
                ask_user_mmc(app, 'Bring sample into focus and to close the Micro-Manager');
                % Load camera through Micro-Manager core
                load_camera_with_mmc(app);
            
                z_focus_value = app.mmc.getPosition;
                z_focus = z_focus_value.*ones(size(app.x_img_mesh));
            
            elseif(strcmp(z_focus_ans,'Use Z-focus grid values') == 1)
                if(app.z_img_mesh == 0)
                    disp('No Z-focus grid loaded.');
                    % Ask user if they want to load a z-focus grid from memory
                    load_z_grid_ans = questdlg('Load Z-focus grid from memory?','No Z-focus grid currently loaded',...
                        'Yes','No','Yes');
                    if(strcmp(load_z_grid_ans,'Use single objective position') == 1)
                        [filename,folder] = uigetfile(app.save_folder);
                        temp_struct = load([folder,'\',filename],'z_img_mesh');
                        app.z_img_mesh = temp_struct.('z_img_mesh');
                    else
                        return
                    end
                end
                z_focus = app.z_img_mesh;
            
                % Ask user to close the Micro-Manager interface
                ask_user_mmc(app, 'Click "Continue" once Micro-Manager has been closed.');
            
                % Load camera through Micro-Manager core
                load_camera_with_mmc(app);
            end
            
            % Assign empty tiles to be 0 in z_focus so they are skipped
            z_focus(app.empty_tile == true) = 0;
            
            % Ask for two exposure times (low and high)
            exp_time_defaults = {'10','100'};
            exp_time = inputdlg({'Exposure time 1 (ms):','Exposure time 2 (ms):'},'Enter exposure times.',[1,60],exp_time_defaults);
            if(isempty(exp_time) == 1)
                return
            else
                exp_times(1,1) = str2double(exp_time{1,1});
                exp_times(2,1) = str2double(exp_time{2,1});
                clear exp_time
            end
            
            % Make folders for the different exposure times
            mkdir(sprintf('exp%dms',exp_times(1,1)));
            mkdir(sprintf('exp%dms',exp_times(2,1)));
            
            % Stitching Acquisition (for ImageJ Stitching plugin):
            % Grid: row-by-row
            % Order: snake (left-to-right row 1)
            disp('Beginning stitching acquisition');
            
            y = 0;
            n_burst = 1;
            img_index = 0;

            for y_loop = 1:size(app.x_img_mesh,1)
                y = y + 1;
            
                for x_loop = 1:size(app.x_img_mesh,2)
                    if(mod(y,2) == 1)
                        % For odd number rows, moving left to right
                        x = x_loop;
                    else
                        % For even number rows, moving right to left
                        x = size(app.x_img_mesh,2) - x_loop + 1;
                    end
                    %move to the preset point
                    y_current = app.y_img_mesh(y,x);
                    x_current = app.x_img_mesh(y,x);
                    move_motor(app,x_current, y_current);
                    pause(0.5);
            
                    % Move objective to z_focus value for the current tile
                    app.mmc.setPosition(z_focus(y,x));
                    pause(0.5);
                    for i = 1:length(exp_times)
                        for n = 1:n_burst % 10-image burst for ff correction
                            %take the image
                            img{1,1,i} = snap_image(exp_times(i,1),app.mmc);
                        end
                    end
            
                    img_index = img_index + 1;
            
                    % Save multi-exposure images for current xy location
                    for i = 1:length(exp_times)
                        cd([app.rectangular_stitch_folder,'\',sprintf('exp%dms',exp_times(i,1))]);
            
                        if(img_index < 10)
                            new_filename = sprintf('img_00%d.tif',img_index);
                        elseif(img_index > 99)
                            new_filename = sprintf('img_%d.tif',img_index);
                        else
                            new_filename = sprintf('img_0%d.tif',img_index);
                        end
                        % Save images with indexing for ImageJ Stitching plug-in
                        imwrite(uint16(img{1,1,i}),new_filename);
                    end
                end
            end
            disp('Done with stitching acquisition. Saving...');
            cd(app.rectangular_stitch_folder);
            save('z_focus.mat','z_focus'); % Redundant with z_img_mesh for tiled image sets that don't use a single focal plane
            disp('Done saving');
 
            app.mmc.reset();
        end

        % Button pushed function: save_coords_button
        function save_coords_button_Callback(app, event)
            save_name = inputdlg('Enter save name:','Save Coordinates',[1,30],{'coordinates'});
            save_name = save_name{1,1};
            saved_coordinates = app.saved_coordinates;
            cd(app.save_folder);
            save([save_name,'.mat'],'saved_coordinates');
        end

        % Button pushed function: set_LR_coords_button
        function set_LR_coords_button_Callback(app, event)
            % read position of the motor and write lower right coordinates of ROI
            [app.LRx, app.LRy] = read_motor_position(app);
            % display selected coordinates
            app.LRx_coord.Text = num2str(app.LRx);
            app.LRy_coord.Text = num2str(app.LRy);
        end

        % Button pushed function: set_UL_coords_button
        function set_UL_coords_button_Callback(app, event)
            % read position of the motor and write to upper left
            % coordinates of ROI
            [app.ULx, app.ULy] = read_motor_position(app);
            % display selected coordinates
            app.ULx_coord.Text = num2str(app.ULx);
            app.ULy_coord.Text = num2str(app.ULy);        
        end

        % Button pushed function: set_grid_button
        function set_grid_button_Callback(app, event)

            % Ask to set grid size (number of rows and cols to image)
            grid_size_defaults = {'8','6'};
            grid_size = inputdlg({'x size:','y size:'},'Enter grid size values.',[1,60],grid_size_defaults);
            if(isempty(grid_size) == 1)
                return
            else
                grid_size_x = str2double(grid_size{1,1});
                grid_size_y = str2double(grid_size{2,1});
                clear grid_size
            end
            app.grid_size = [grid_size_y, grid_size_x];
            app.grid_size_display.Text = sprintf('%d x %d',grid_size_x,grid_size_y);
        end

        % Button pushed function: set_low_right_button
        function set_low_right_button_Callback(app, event)
            % read position of the motor
            [app.LRx, app.LRy] = read_motor_position(app);
            app.ULx_coord.Text = num2str(app.ULx);
            app.ULy_coord.Text = num2str(app.ULy);
            
            if(all([app.LRx, app.LRy, app.grid_size]) == 1)
                % If all XY stitching coordinates have been entered
                app.rectangular_stitching = 1;
            
                % Choose magnification
                [mag, app.mag_index] = choose_magnification(app);
            
                % Compute actual xy locations for rectangular grid acquisition
                x_min = app.LRx;
                x_step =app.FOV_microns_stitching_x(app.mag_index)/1000; % convert FOV in microns to mm
                grid_size_x = app.grid_size(1,2);
            
                y_max = app.LRy;
                y_step = app.FOV_microns_stitching_y(app.mag_index)/1000; % convert FOV in microns to mm
                grid_size_y = app.grid_size(1,1); 
                
                y_min = y_max - y_step*(grid_size_y-1); % minimum coordinate for y axis
                y_img = y_min:y_step:y_max;
                y_plot_grid = (y_min - y_step/2):y_step:(y_max + y_step/2); % Step is divided by 2 to center frame over boundary
            
                x_max = x_min+x_step*(grid_size_x-1); % maximum coordinate for x axis
                x_img = x_min:x_step:x_max;
                x_plot_grid = (x_min-x_step/2):x_step:(x_max+x_step/2); % Step is divided by 2 to center frame over boundary
            
                % x_img and y_img - center points of each image
                [app.x_grid_mesh,app.y_grid_mesh] = meshgrid(x_plot_grid,y_plot_grid);
                [app.x_img_mesh,app.y_img_mesh] = meshgrid(x_img,y_img);
                
                %grid plotting
                fig1 = plot_grid(app, x_min, x_max, y_min, y_max);
                input_save_filename(app);
                
                %save coordinates for opposite corner
                app.ULx = x_max;
                app.ULy = y_min;          
                app.ULx_coord.Text = num2str(app.ULx);
                app.ULy_coord.Text = num2str(app.ULy);
            
                %save coordinates
                x_img_mesh = app.x_img_mesh;
                y_img_mesh = app.y_img_mesh;
                x_grid_mesh = app.x_grid_mesh;
                y_grid_mesh = app.y_grid_mesh;
                rect = app.rectangular_stitching;
                save('image_xy_coords.mat','x_img_mesh','y_img_mesh','x_grid_mesh','y_grid_mesh','mag','rect');
                disp('XY coordinates for stitching acquisition saved and ready.');
                app.stitching_acquisition_display.Text = 'Ready';
            else
                % error
                % Missing XY stitching coordinates (UL and LR)
                d = dialog('Position',[300 300 400 100],'Name','Coordinates or Grid Size Missing');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    'The upper left and lower right coordinates have not been set. Close this dialog box and set them.');
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Close',...
                    'Callback','delete(gcf)');
                uiwait(d);
                return;
            end
            close(fig1);
        end

        % Button pushed function: set_up_left_button
        function set_up_left_button_Callback(app, event)
            % read position of the motor
            [app.ULx, app.ULy] = read_motor_position(app);
            app.ULx_coord.Text = num2str(app.ULx);
            app.ULy_coord.Text = num2str(app.ULy);

            if(all([app.ULx, app.ULy, app.grid_size]) == 1)
                % If all XY stitching coordinates have been entered
                app.rectangular_stitching = 1;
            
                % Choose magnification
                [mag, app.mag_index] = choose_magnification(app);
            
                % Compute actual xy locations for rectangular grid acquisition
                x_max = app.ULx;
                x_step =app.FOV_microns_stitching_x(app.mag_index)/1000; % convert FOV in microns to mm
                grid_size_x = app.grid_size(1,2);
            
                y_min = app.ULy;
                y_step = app.FOV_microns_stitching_y(app.mag_index)/1000; % convert FOV in microns to mm
                grid_size_y = app.grid_size(1,1);
            
                x_min = x_max-x_step*(grid_size_x-1); % minimum coordinate for x axis
                x_img = x_min:x_step:x_max;
                x_plot_grid = (x_min - x_step/2):x_step:(x_max + x_step/2); % Step is divided by 2 to center frame over boundary
            
                y_max = y_min+y_step*(grid_size_y-1); % maximum coordinate for y axis
                y_img = y_min:y_step:y_max;
                y_plot_grid = (y_min-y_step/2):y_step:(y_max+y_step/2); % Step is divided by 2 to center frame over boundary
                
                % x_img and y_img - center points of each image
                [app.x_grid_mesh,app.y_grid_mesh] = meshgrid(x_plot_grid,y_plot_grid);
                [app.x_img_mesh,app.y_img_mesh] = meshgrid(x_img,y_img);
                
                %grid plotting
                fig1 = plot_grid(app, x_min, x_max, y_min, y_max);
                input_save_filename(app);
                
                %save coordinates for opposite corner
                app.LRx = x_min;
                app.LRy = y_max;          
                app.LRx_coord.Text = num2str(app.LRx);
                app.LRy_coord.Text = num2str(app.LRy);
            
                %save coordinates
                x_img_mesh = app.x_img_mesh;
                y_img_mesh = app.y_img_mesh;
                x_grid_mesh = app.x_grid_mesh;
                y_grid_mesh = app.y_grid_mesh;
                rect = app.rectangular_stitching;
                save('image_xy_coords.mat','x_img_mesh','y_img_mesh','x_grid_mesh','y_grid_mesh','mag','rect');
                disp('XY coordinates for stitching acquisition saved and ready.');
                app.stitching_acquisition_display.Text = 'Ready';
            else
                % error
                % Missing XY stitching coordinates (UL and LR)
                d = dialog('Position',[300 300 400 100],'Name','Coordinates or Grid Size Missing');
                d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                    'The upper left and lower right coordinates have not been set. Close this dialog box and set them.');
                d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Close',...
                    'Callback','delete(gcf)');
                uiwait(d);
                return;
            end
            close(fig1);
        end

        % Button pushed function: sim_rotate_button
        function sim_rotate_button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sim_rotate_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            angle_string = get(app.LP_slider_display,'String');
            angle_end_index = regexp(angle_string,' ');
            slider_setting = str2double(angle_string(1:angle_end_index-1));
            app.motor_ill.SetAbsMovePos(0,app.ill_degree_settings(1,(slider_setting/5)+1));
            app.motor_det.SetAbsMovePos(0,app.det_degree_settings(1,(slider_setting/5)+1));
            app.motor_ill.MoveAbsolute(0,0);
            app.motor_det.MoveAbsolute(0,0);
            moving = 1;
            timeout = 20;
            current_time = clock;
            while(moving == 1 && etime(clock,current_time) < timeout)
                ill_status = app.motor_ill.GetStatusBits_Bits(0);
                det_status = app.motor_det.GetStatusBits_Bits(0);
                moving = are_K10CR1s_moving(ill_status,det_status);
            end
            set(app.LP_angle_display,'String',sprintf('%d degrees',slider_setting));
            set(app.ill_pol_display,'String',sprintf('%.4f degrees',app.ill_degree_settings(1,(slider_setting/5)+1)));
            set(app.det_pol_display,'String',sprintf('%.4f degrees',app.det_degree_settings(1,(slider_setting/5)+1)));
            guidata(hObject,handles);
        end

        % Button pushed function: zstack_acquisition_button
        function zstack_acquisition_button_Callback(app, event)
            % if micro-manager is open, ask user to close it
            ask_user_mmc(app, 'Click "Continue" once Micro-Manager has been closed.');
            
            % Ask user if they are using the 40X or 60X objective
            objective_ans = questdlg('Are you using the 40X air or 60X oil immersion objective?','40X air or 60X oil?','40X air','60X oil','40X air');
            objective_select = '40X';
            if(strcmp(objective_ans,'40X air') == 1)
                objective_select = '40X';         
                % Ask user if they wish to use the auto-focus function
                auto_focus_ans = questdlg('Do you wish to auto-focus?','Auto-focus?','Yes','No - Use z-positions from the last z-stack','Yes');
                auto_focus = 1;
                if(strcmp(auto_focus_ans,'Yes') == 1)
                    auto_focus = 1;
                elseif(strcmp(auto_focus_ans,'No - Use z-positions from the last z-stack') == 1)
                    if(app.saved_z_positions == 0)
                        % If there are no previously saved z-positions
                        d = dialog('Position',[300 300 400 100],'Name','Invalid Selection');
                        d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                            'There are no previously saved z-positions. Click "Continue" to proceed with auto-focus.');
                        d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                            'Callback','delete(gcf)');
                        uiwait(d);
                    else
                        % If there are previously saved z-positions
                        auto_focus = 0;
                    end
                end
            
            elseif(strcmp(objective_ans,'60X oil') == 1)
                objective_select = '60X';
                auto_focus = 0;
            
                % Ask user if the z-stack should be taken with the last z_values or if new ones should be determined
                retake = questdlg('Do you wish to retake the z-stack with the last z-values used?','Use last z-values?','Yes','No','No');
                if(strcmp(retake,'Yes') == 1)
                    % Do nothing
                elseif(strcmp(retake,'No') == 1)
                    % Ask the user to bring the bottom of the sample into focus with Micro-Manager
                    d = dialog('Position',[300 300 400 100],'Name','Bring bottom of sample into focus with Micro-Manager');
                    d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                        'Click "Continue" once the bottom of the sample has been brought into focus and Micro-Manager has been closed.');
                    d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                        'Callback','delete(gcf)');
                    uiwait(d);
            
                    % Load camera through Micro-Manager core
                    import mmcorej.*;
                    app.mmc = CMMCore;
                    app.mmc.loadSystemConfiguration ('C:\Program Files\Micro-Manager-2.0gamma\MMConfig_full_microscope_control.cfg'); % Config file with z-stage control
            
                    % Get bottom z-position
                    z_bottom = app.mmc.getPosition;
            
                    % Ask the user to bring the top of the sample into focus with Micro-Manager
                    app.mmc.reset();
                    !C:\Program Files\Micro-Manager-2.0gamma\ImageJ.exe
                    d = dialog('Position',[300 300 400 100],'Name','Bring top of sample into focus with Micro-Manager');
                    d_text = uicontrol('Parent',d,'Style','text','Position',[20 50 350 40],'String',...
                        'Click "Continue" once the top of the sample has been brought into focus and Micro-Manager has been closed.');
                    d_button = uicontrol('Parent',d,'Position',[155 20 70 25],'String','Continue',...
                        'Callback','delete(gcf)');
                    uiwait(d);
            
                    % Load camera through Micro-Manager core
                    import mmcorej.*;
                    app.mmc = CMMCore;
                    app.mmc.loadSystemConfiguration ('C:\Program Files\Micro-Manager-2.0gamma\MMConfig_full_microscope_control.cfg'); % Config file with z-stage control
            
                    % Get top z-position
                    z_top = app.mmc.getPosition;
                    app.mmc.reset();
            
                    % Create z-focus vector
                    if(z_bottom < z_top)
                        z_values = floor(z_bottom):1:ceil(z_top);
                    else
                        z_values = floor(z_top):1:ceil(z_bottom);
                    end
                    app.saved_z_positions = z_values';
                    save('z_values.mat','z_values');          
                end         
            end
            
            
            % Load camera through Micro-Manager core
            import mmcorej.*;
            app.mmc = CMMCore;
            app.mmc.loadSystemConfiguration ('C:\Program Files\Micro-Manager-2.0gamma\MMConfig_full_microscope_control.cfg'); % Config file with z-stage control
            
            app.camera_status.Text = 'MATLAB';
            
            z_step = 1;
            z_range = 40; % Capture over 40 microns of objective translation in z
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%% Automated acquisition --> snap in-focus 10X image and 40X z-stack %%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if(auto_focus == 1)
                % Find focus for 10X objective at 5o ms exposure time
                exp_time = 25;
                correction_factor = 15;
                [img, current_z] = autofocusing(app, correction_factor, exp_time, '10X');
                z_min = current_z - z_range/2;
                z_max = current_z + z_range/2;
            
                app.saved_z_positions = (z_min:z_step:z_max)'; % The z-positions that will be used for z-stack acquisition
            
                fig1 = figure('Position',[1000 200 800 600],'Name','10X Image');
                imshow(img);
            
                % Ask user if the 10X image should be retaken with another exposure time before moving on
                done = 0;
                while(done == 0)
                    retake = questdlg('Do you wish to retake the image with a new exposure time?','Retake 10X image?','Yes','No','No');
                    if(strcmp(retake,'Yes') == 1)
                        exposure_string = sprintf('This image was taken with an exposure time of %d ms. Enter a new exposure time (ms): ',exp_time);
                        new_exp_time = inputdlg(exposure_string,'Enter a new exposure time.',[1,90]);
                        exp_time = str2double(new_exp_time{1,1});
                        close(fig1);
                        img = snap_image(exp_time,app.mmc);
                        fig1 = figure('Position',[1000 200 800 600],'Name','10X Image');
                        imshow(img);
                    elseif(strcmp(retake,'No') == 1)
                        done = 1;
                    end
                end
                img10x = img;
            end
            
            % Change to selected objective
            app.mmc.setConfig('Objective',objective_select);
            exp_time = str2double(app.zstack_exp_time_user_entry.Value);
            num_z_steps = length(app.saved_z_positions);
            done = 0;
            while(done == 0)
                n = 0;
                zstack = zeros([app.FOV_pixels',num_z_steps+1]);
                zstack_fm = zeros(num_z_steps+1,1);
                % find z with the maximum focus (sharpness)
                for z = app.saved_z_positions(1,1):z_step:app.saved_z_positions(num_z_steps,1)
                    n = n + 1;
                    app.mmc.setPosition(z);
                    pause(0.5);
                    zstack(:,:,n) = double(snap_image(exp_time,app.mmc));
                    zstack_fm(n,1) = fmeasure(zstack(:,:,n),'BREN');
                end
                [fm_max(1,1), fm_max(1,2)] = max(zstack_fm);
            
                fig2 = figure('Position',[150 200 800 600],'Name','Focal Measure');
                plot(1:size(zstack_fm,1),zstack_fm,'o');
                fig3 = figure('Position',[1000 200 800 600],'Name','Z-Stack');
                imshow3D(zstack);
            
                % Ask user if the z-stack should be retaken with another exposure time before moving on
                retake = questdlg('Do you wish to retake the z-stack with a new exposure time?','Retake z-stack?','Yes','No','No');
                if(strcmp(retake,'Yes') == 1)
                    exposure_string = sprintf('This z-stack was taken with an exposure time of %d ms. Enter a new exposure time (ms): ',exp_time);
                    new_exp_time = inputdlg(exposure_string,'Enter a new exposure time.',[1,90]);
                    exp_time = str2double(new_exp_time{1,1});
                    close(fig2);
                    close(fig3);
                elseif(strcmp(retake,'No') == 1)
                    done = 1;
                end
            end
            
            % Ask user if they want to save the 10X image and high magnification z-stack
            save_data = questdlg('Do you wish to save this z-stack?','Save z-stack?','Yes','No','Yes');
            if(strcmp(save_data,'Yes') == 1)
                app.i_zstack = app.i_zstack + 1;
                save_string = sprintf('zstack_%d',app.i_zstack);
                save_name = inputdlg('Enter save name without any extensions:','Enter save name.',[1,50],{save_string});
                save_name = save_name{1,1};
            
                cd(app.save_folder);
                mkdir(save_name); % Organize with unique folder names
                cd([app.save_folder,'\',save_name]);
                z_values = app.saved_z_positions;
                if(auto_focus == 1)
                    save('images.mat','img10x','zstack','z_values','fm_max','-v7.3');
                    imwrite(img10x,'img_10X.tif');
                else
                    save('images.mat','zstack','z_values','fm_max','-v7.3');
                end
            
                for i = 1:size(zstack,3)
                    imwrite(uint16(zstack(:,:,i)),'zstack.tif','tif','WriteMode','append');
                end
                cd(app.save_folder);
            end
            
            app.mmc.reset();
            fprintf('Image acquisition is finished');
        end

        % Value changed function: zstack_exp_time_user_entry
        function zstack_exp_time_user_entry_Callback(app, event)
            value = app.zstack_exp_time_user_entry.Value;
            
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            disconnect_all_motors(app);
            delete(app);
        end

        % Value changed function: x_coord_entry
        function x_coord_entryValueChanged(app, event)
            app.x_coord_motor = str2double(app.x_coord_entry.Value);
            
        end

        % Value changed function: y_coord_entry
        function y_coord_entryValueChanged(app, event)
            app.y_coord_motor = str2double(app.y_coord_entry.Value);
            
        end

        % Button pushed function: MoveXYmotorButton
        function MoveXYmotorButtonPushed(app, event)
            move_motor(app, app.x_coord_motor, app.y_coord_motor);
        end

        % Button pushed function: update_xymotor_coords
        function update_xymotor_coordsButtonPushed(app, event)
            % update motor position
            [x_coord, y_coord] = read_motor_position(app);
            app.x_coord_text.Text = num2str(x_coord);
            app.y_coord_text.Text = num2str(y_coord);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'control_new_offline_copy';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.Tag = 'figure1';
            app.custom_acquisition_button = uibutton(app.figure1, 'push');
            app.custom_acquisition_button.ButtonPushedFcn = createCallbackFcn(app, @custom_acquisition_button_Callback, true);
            app.custom_acquisition_button.Tag = 'custom_acquisition_button';
            app.custom_acquisition_button.Visible = 'off';
            app.custom_acquisition_button.Text = 'Custom Acquisition 1';
            app.custom_acquisition_2_button = uibutton(app.figure1, 'push');
            app.custom_acquisition_2_button.ButtonPushedFcn = createCallbackFcn(app, @custom_acquisition_2_button_Callback, true);
            app.custom_acquisition_2_button.Tag = 'custom_acquisition_2_button';
            app.custom_acquisition_2_button.Visible = 'off';
            app.custom_acquisition_2_button.Text = 'Custom Acquisition 2';
            app.Panel = uipanel(app.figure1);
            app.Panel.Title = 'Panel';
            app.z_coord_list = uilistbox(app.Panel);
            app.z_coord_list.Items = {'Save/Load Coordinates'};
            app.z_coord_list.Tag = 'z_coord_list';
            app.z_coord_list.Value = 'Save/Load Coordinates';
            app.mm_button = uibutton(app.Panel, 'push');
            app.mm_button.ButtonPushedFcn = createCallbackFcn(app, @mm_button_Callback, true);
            app.mm_button.Tag = 'mm_button';
            app.mm_button.Text = 'Live View (Open Micro-Manager GUI)';
            app.save_dir_user_entry = uieditfield(app.Panel, 'text');
            app.save_dir_user_entry.Tag = 'save_dir_user_entry';
            app.browse_button = uibutton(app.Panel, 'push');
            app.browse_button.ButtonPushedFcn = createCallbackFcn(app, @browse_button_Callback, true);
            app.browse_button.Tag = 'browse_button';
            app.browse_button.Text = 'Browse...';
            app.text2 = uilabel(app.Panel);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Save Directory:';
            app.lcp_rca_button = uibutton(app.Panel, 'push');
            app.lcp_rca_button.ButtonPushedFcn = createCallbackFcn(app, @lcp_rca_button_Callback, true);
            app.lcp_rca_button.Tag = 'lcp_rca_button';
            app.lcp_rca_button.Text = 'LCP-RCA';
            app.rcp_lca_button = uibutton(app.Panel, 'push');
            app.rcp_lca_button.ButtonPushedFcn = createCallbackFcn(app, @rcp_lca_button_Callback, true);
            app.rcp_lca_button.Tag = 'rcp_lca_button';
            app.rcp_lca_button.Text = 'RCP-LCA';
            app.text3 = uilabel(app.Panel);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Crossed Circular Polarizers:';
            app.text4 = uilabel(app.Panel);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Crossed Linear Polarizers:';
            app.sim_rotate_button = uibutton(app.Panel, 'push');
            app.sim_rotate_button.ButtonPushedFcn = createCallbackFcn(app, @sim_rotate_button_Callback, true);
            app.sim_rotate_button.Tag = 'sim_rotate_button';
            app.sim_rotate_button.Text = 'Simultaneous Rotation';
            app.text5 = uilabel(app.Panel);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Rotating Linear Polarizer, Circular Analyzer:';
            app.rLP_rca_button = uibutton(app.Panel, 'push');
            app.rLP_rca_button.ButtonPushedFcn = createCallbackFcn(app, @rLP_rca_button_Callback, true);
            app.rLP_rca_button.Tag = 'rLP_rca_button';
            app.rLP_rca_button.Text = 'rLP-RCA';
            app.rLP_lca_button = uibutton(app.Panel, 'push');
            app.rLP_lca_button.ButtonPushedFcn = createCallbackFcn(app, @rLP_lca_button_Callback, true);
            app.rLP_lca_button.Tag = 'rLP_lca_button';
            app.rLP_lca_button.Text = 'rLP-LCA';
            app.text6 = uilabel(app.Panel);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Current Angle:';
            app.text7 = uilabel(app.Panel);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Slider Setting:';
            app.LP_angle_display = uilabel(app.Panel);
            app.LP_angle_display.Tag = 'LP_angle_display';
            app.LP_angle_display.Text = 'NaN';
            app.LP_slider_display = uilabel(app.Panel);
            app.LP_slider_display.Tag = 'LP_slider_display';
            app.LP_slider_display.Text = '';
            app.text13 = uilabel(app.Panel);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Camera Status:';
            app.camera_status = uilabel(app.Panel);
            app.camera_status.Tag = 'camera_status';
            app.camera_status.Text = 'None';
            app.bf_button_rcp_rca = uibutton(app.Panel, 'push');
            app.bf_button_rcp_rca.ButtonPushedFcn = createCallbackFcn(app, @bf_button_rcp_rca_Callback, true);
            app.bf_button_rcp_rca.Tag = 'bf_button_rcp_rca';
            app.bf_button_rcp_rca.Text = 'Brightfield (RCP-RCA)';
            app.text39 = uilabel(app.Panel);
            app.text39.Tag = 'text39';
            app.text39.Text = 'Unpolarized Light (Brightfield):';
            app.bf_button_lcp_lca = uibutton(app.Panel, 'push');
            app.bf_button_lcp_lca.ButtonPushedFcn = createCallbackFcn(app, @bf_button_lcp_lca_Callback, true);
            app.bf_button_lcp_lca.Tag = 'bf_button_lcp_lca';
            app.bf_button_lcp_lca.Text = 'Brightfield (LCP-LCA)';
            app.coord_list = uilistbox(app.Panel);
            app.coord_list.Items = {'Save/Load Coordinates'};
            app.coord_list.Tag = 'coord_list';
            app.coord_list.Value = 'Save/Load Coordinates';
            app.text42 = uilabel(app.Panel);
            app.text42.Tag = 'text42';
            app.text42.Text = 'Coordinate List:';
            app.go_to_coords_button = uibutton(app.Panel, 'push');
            app.go_to_coords_button.ButtonPushedFcn = createCallbackFcn(app, @go_to_coords_button_Callback, true);
            app.go_to_coords_button.Tag = 'go_to_coords_button';
            app.go_to_coords_button.Text = 'Go To Selected Coordinates';
            app.add_new_coords_button = uibutton(app.Panel, 'push');
            app.add_new_coords_button.ButtonPushedFcn = createCallbackFcn(app, @add_new_coords_button_Callback, true);
            app.add_new_coords_button.Tag = 'add_new_coords_button';
            app.add_new_coords_button.Text = 'Add Current Coordinates to List';
            app.text45 = uilabel(app.Panel);
            app.text45.Tag = 'text45';
            app.text45.Text = 'X';
            app.text46 = uilabel(app.Panel);
            app.text46.Tag = 'text46';
            app.text46.Text = 'Y';
            app.save_coords_button = uibutton(app.Panel, 'push');
            app.save_coords_button.ButtonPushedFcn = createCallbackFcn(app, @save_coords_button_Callback, true);
            app.save_coords_button.Tag = 'save_coords_button';
            app.save_coords_button.Text = 'Save List...';
            app.load_coords_button = uibutton(app.Panel, 'push');
            app.load_coords_button.ButtonPushedFcn = createCallbackFcn(app, @load_coords_button_Callback, true);
            app.load_coords_button.Tag = 'load_coords_button';
            app.load_coords_button.Text = 'Load List...';
            app.calibrate_z_10x_button = uibutton(app.Panel, 'push');
            app.calibrate_z_10x_button.ButtonPushedFcn = createCallbackFcn(app, @calibrate_z_10x_button_Callback, true);
            app.calibrate_z_10x_button.Tag = 'calibrate_z_10x_button';
            app.calibrate_z_10x_button.Text = 'Calibrate Z (10X)';
            app.text50 = uilabel(app.Panel);
            app.text50.Tag = 'text50';
            app.text50.Text = 'Z Coordinates:';
            app.calibrate_z_40x_button = uibutton(app.Panel, 'push');
            app.calibrate_z_40x_button.ButtonPushedFcn = createCallbackFcn(app, @calibrate_z_40x_button_Callback, true);
            app.calibrate_z_40x_button.Tag = 'calibrate_z_40x_button';
            app.calibrate_z_40x_button.Text = 'Calibrate Z (40X)';
            app.reset_camera_button = uibutton(app.Panel, 'push');
            app.reset_camera_button.ButtonPushedFcn = createCallbackFcn(app, @reset_camera_button_Callback, true);
            app.reset_camera_button.Tag = 'reset_camera_button';
            app.reset_camera_button.Text = 'Reset Camera Comm. Interface';
            app.text51 = uilabel(app.Panel);
            app.text51.Tag = 'text51';
            app.text51.Text = 'Linear Polarizers:';
            app.text52 = uilabel(app.Panel);
            app.text52.Tag = 'text52';
            app.text52.Text = 'Illumination:';
            app.text53 = uilabel(app.Panel);
            app.text53.Tag = 'text53';
            app.text53.Text = 'Detection:';
            app.ill_pol_user_entry = uieditfield(app.Panel, 'text');
            app.ill_pol_user_entry.Tag = 'ill_pol_user_entry';
            app.det_pol_user_entry = uieditfield(app.Panel, 'text');
            app.det_pol_user_entry.Tag = 'det_pol_user_entry';
            app.text54 = uilabel(app.Panel);
            app.text54.Tag = 'text54';
            app.text54.Text = 'Camera:';
            app.text55 = uilabel(app.Panel);
            app.text55.Tag = 'text55';
            app.text55.Text = 'Current Setting';
            app.ill_pol_rotate_button = uibutton(app.Panel, 'push');
            app.ill_pol_rotate_button.ButtonPushedFcn = createCallbackFcn(app, @ill_pol_rotate_button_Callback, true);
            app.ill_pol_rotate_button.Tag = 'ill_pol_rotate_button';
            app.ill_pol_rotate_button.Text = 'Rotate';
            app.det_pol_rotate_button = uibutton(app.Panel, 'push');
            app.det_pol_rotate_button.ButtonPushedFcn = createCallbackFcn(app, @det_pol_rotate_button_Callback, true);
            app.det_pol_rotate_button.Tag = 'det_pol_rotate_button';
            app.det_pol_rotate_button.Text = 'Rotate';
            app.ill_pol_display = uilabel(app.Panel);
            app.ill_pol_display.Tag = 'ill_pol_display';
            app.ill_pol_display.Text = 'NaN';
            app.det_pol_display = uilabel(app.Panel);
            app.det_pol_display.Tag = 'det_pol_display';
            app.det_pol_display.Text = 'NaN';
            app.clear_coords_button = uibutton(app.Panel, 'push');
            app.clear_coords_button.ButtonPushedFcn = createCallbackFcn(app, @clear_coords_button_Callback, true);
            app.clear_coords_button.Tag = 'clear_coords_button';
            app.clear_coords_button.Text = 'Clear Coordinate List';
            app.Panel2 = uipanel(app.Panel);
            app.Panel2.Title = 'Panel2';
            app.run_sp_af_stitch_button = uibutton(app.Panel2, 'push');
            app.run_sp_af_stitch_button.ButtonPushedFcn = createCallbackFcn(app, @run_sp_af_stitch_button_Callback, true);
            app.run_sp_af_stitch_button.Tag = 'run_sp_af_stitch_button';
            app.run_sp_af_stitch_button.Text = 'Run Single-Plane Autofocus Stitching Acquisition';
            app.zstack_acquisition_button = uibutton(app.Panel2, 'push');
            app.zstack_acquisition_button.ButtonPushedFcn = createCallbackFcn(app, @zstack_acquisition_button_Callback, true);
            app.zstack_acquisition_button.Tag = 'zstack_acquisition_button';
            app.zstack_acquisition_button.Text = 'Acquire Z-Stack';
            app.zstack_exp_time_user_entry = uieditfield(app.Panel2, 'text');
            app.zstack_exp_time_user_entry.ValueChangedFcn = createCallbackFcn(app, @zstack_exp_time_user_entry_Callback, true);
            app.zstack_exp_time_user_entry.Tag = 'zstack_exp_time_user_entry';
            app.zstack_exp_time_user_entry.Value = '500';
            app.text49 = uilabel(app.Panel2);
            app.text49.Tag = 'text49';
            app.text49.Text = 'Z-Stack Exposure Time (ms):';
            app.run_mp_stitch_button = uibutton(app.Panel2, 'push');
            app.run_mp_stitch_button.ButtonPushedFcn = createCallbackFcn(app, @run_mp_stitch_button_Callback, true);
            app.run_mp_stitch_button.Tag = 'run_mp_stitch_button';
            app.run_mp_stitch_button.Text = 'Run Multi-Plane Stitching Acquisition';
            app.text63 = uilabel(app.Panel2);
            app.text63.Tag = 'text63';
            app.text63.Text = 'Stitching Perimeter:';
            app.stitching_perimeter_display = uilabel(app.Panel2);
            app.stitching_perimeter_display.Tag = 'stitching_perimeter_display';
            app.stitching_perimeter_display.Text = 'None';
            app.text65 = uilabel(app.Panel2);
            app.text65.Tag = 'text65';
            app.text65.Text = 'Stitching Acquisition:';
            app.stitching_acquisition_display = uilabel(app.Panel2);
            app.stitching_acquisition_display.Tag = 'stitching_acquisition_display';
            app.stitching_acquisition_display.Text = 'Not Ready';
            app.run_sp_stitch_button = uibutton(app.Panel2, 'push');
            app.run_sp_stitch_button.ButtonPushedFcn = createCallbackFcn(app, @run_sp_stitch_button_Callback, true);
            app.run_sp_stitch_button.Tag = 'run_sp_stitch_button';
            app.run_sp_stitch_button.Text = 'Run Single-Plane Stitching Acquisition';
            app.run_sp_qBRM_stitch_button = uibutton(app.Panel2, 'push');
            app.run_sp_qBRM_stitch_button.ButtonPushedFcn = createCallbackFcn(app, @run_sp_qBRM_stitch_button_Callback, true);
            app.run_sp_qBRM_stitch_button.Tag = 'run_sp_qBRM_stitch_button';
            app.run_sp_qBRM_stitch_button.Text = 'Run Single-Plane qBRM Stitching Acquisition';
            app.determine_z_focus_grid = uibutton(app.Panel2, 'push');
            app.determine_z_focus_grid.ButtonPushedFcn = createCallbackFcn(app, @determine_z_focus_grid_Callback, true);
            app.determine_z_focus_grid.Tag = 'determine_z_focus_grid';
            app.determine_z_focus_grid.Text = 'Determine Z-Focus for Grid';
            app.go_UL_button = uibutton(app.Panel2, 'push');
            app.go_UL_button.ButtonPushedFcn = createCallbackFcn(app, @go_UL_button_Callback, true);
            app.go_UL_button.Tag = 'go_UL_button';
            app.go_UL_button.Text = 'Go to upper left';
            app.go_LR_button = uibutton(app.Panel2, 'push');
            app.go_LR_button.ButtonPushedFcn = createCallbackFcn(app, @go_LR_button_Callback, true);
            app.go_LR_button.Tag = 'go_LR_button';
            app.go_LR_button.Text = 'Go to lower right';
            app.TabGroup = uitabgroup(app.Panel2);
            app.cornersTab = uitab(app.TabGroup);
            app.cornersTab.Title = '2 corners';
            app.calculate_grid_button = uibutton(app.cornersTab, 'push');
            app.calculate_grid_button.ButtonPushedFcn = createCallbackFcn(app, @calculate_grid_button_Callback, true);
            app.calculate_grid_button.Tag = 'calculate_grid_button';
            app.calculate_grid_button.Text = 'Calculate Grid Size';
            app.Image = uiimage(app.cornersTab);
            app.Image.ImageSource = 'box.png';
            app.set_UL_coords_button = uibutton(app.cornersTab, 'push');
            app.set_UL_coords_button.ButtonPushedFcn = createCallbackFcn(app, @set_UL_coords_button_Callback, true);
            app.set_UL_coords_button.Tag = 'set_UL_coords_button';
            app.set_UL_coords_button.Text = 'Set UL';
            app.set_LR_coords_button = uibutton(app.cornersTab, 'push');
            app.set_LR_coords_button.ButtonPushedFcn = createCallbackFcn(app, @set_LR_coords_button_Callback, true);
            app.set_LR_coords_button.Tag = 'set_LR_coords_button';
            app.set_LR_coords_button.Text = 'Set LR';
            app.cornergridTab = uitab(app.TabGroup);
            app.cornergridTab.Title = '1 corner + grid';
            app.set_grid_button = uibutton(app.cornergridTab, 'push');
            app.set_grid_button.ButtonPushedFcn = createCallbackFcn(app, @set_grid_button_Callback, true);
            app.set_grid_button.Tag = 'set_grid_button';
            app.set_grid_button.Text = 'Set Grid Size';
            app.Image_2 = uiimage(app.cornergridTab);
            app.Image_2.ImageSource = 'box.png';
            app.set_up_left_button = uibutton(app.cornergridTab, 'push');
            app.set_up_left_button.ButtonPushedFcn = createCallbackFcn(app, @set_up_left_button_Callback, true);
            app.set_up_left_button.Tag = 'set_up_left_button';
            app.set_up_left_button.Text = 'Set UL';
            app.set_low_right_button = uibutton(app.cornergridTab, 'push');
            app.set_low_right_button.ButtonPushedFcn = createCallbackFcn(app, @set_low_right_button_Callback, true);
            app.set_low_right_button.Tag = 'set_low_right_button';
            app.set_low_right_button.Text = 'Set LR';
            app.XYstageControlPanel = uipanel(app.Panel2);
            app.XYstageControlPanel.Title = 'XY-stage Control';
            app.x_coord_entry = uieditfield(app.XYstageControlPanel, 'text');
            app.x_coord_entry.ValueChangedFcn = createCallbackFcn(app, @x_coord_entryValueChanged, true);
            app.x_coord_entry.Tag = 'x_coord_entry';
            app.x_coord_entry.Value = '55';
            app.y_coord_entry = uieditfield(app.XYstageControlPanel, 'text');
            app.y_coord_entry.ValueChangedFcn = createCallbackFcn(app, @y_coord_entryValueChanged, true);
            app.y_coord_entry.Tag = 'y_coord_entry';
            app.y_coord_entry.Value = '37.5';
            app.text54_2 = uilabel(app.XYstageControlPanel);
            app.text54_2.Tag = 'text54';
            app.text54_2.Text = 'X-motor';
            app.text54_3 = uilabel(app.XYstageControlPanel);
            app.text54_3.Tag = 'text54';
            app.text54_3.Text = 'Y-motor';
            app.MoveXYmotorButton = uibutton(app.XYstageControlPanel, 'push');
            app.MoveXYmotorButton.ButtonPushedFcn = createCallbackFcn(app, @MoveXYmotorButtonPushed, true);
            app.MoveXYmotorButton.Text = 'Move XY-motor';
            app.text54_4 = uilabel(app.XYstageControlPanel);
            app.text54_4.Tag = 'text54';
            app.text54_4.Text = 'Move to';
            app.text54_5 = uilabel(app.XYstageControlPanel);
            app.text54_5.Tag = 'text54';
            app.text54_5.Text = {'Current '; 'position'};
            app.x_coord_text = uilabel(app.XYstageControlPanel);
            app.x_coord_text.Tag = 'text54';
            app.x_coord_text.Text = 'NaN';
            app.y_coord_text = uilabel(app.XYstageControlPanel);
            app.y_coord_text.Tag = 'text54';
            app.y_coord_text.Text = 'NaN';
            app.update_xymotor_coords = uibutton(app.XYstageControlPanel, 'push');
            app.update_xymotor_coords.ButtonPushedFcn = createCallbackFcn(app, @update_xymotor_coordsButtonPushed, true);
            app.update_xymotor_coords.Text = 'Update coordinates';
            app.text18 = uilabel(app.Panel2);
            app.text18.Tag = 'text18';
            app.text18.Text = 'X';
            app.text19 = uilabel(app.Panel2);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Y';
            app.ULx_coord = uilabel(app.Panel2);
            app.ULx_coord.Tag = 'ULx_coord';
            app.ULx_coord.Text = 'NaN';
            app.ULy_coord = uilabel(app.Panel2);
            app.ULy_coord.Tag = 'ULy_coord';
            app.ULy_coord.Text = 'NaN';
            app.LRy_coord = uilabel(app.Panel2);
            app.LRy_coord.Tag = 'LRy_coord';
            app.LRy_coord.Text = 'NaN';
            app.LRx_coord = uilabel(app.Panel2);
            app.LRx_coord.Tag = 'LRx_coord';
            app.LRx_coord.Text = 'NaN';
            app.text40 = uilabel(app.Panel2);
            app.text40.Tag = 'text40';
            app.text40.Text = 'Grid Size:';
            app.grid_size_display = uilabel(app.Panel2);
            app.grid_size_display.Tag = 'grid_size_display';
            app.grid_size_display.Text = 'NaN';
            app.load_grid_button = uibutton(app.Panel2, 'push');
            app.load_grid_button.ButtonPushedFcn = createCallbackFcn(app, @load_grid_button_Callback, true);
            app.load_grid_button.Tag = 'load_grid_button';
            app.load_grid_button.Text = 'Load Grid';
            app.LP_angle_slider = uislider(app.Panel);
            app.LP_angle_slider.Limits = [1 360];
            app.LP_angle_slider.Value = 1;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = control_new_offline_copy_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end